classdef vocOperant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MetadataPanel                   matlab.ui.container.Panel
        BatinthebackEditFieldLabel      matlab.ui.control.Label
        Batintheback                    matlab.ui.control.EditField
        BatinthefrontEditFieldLabel     matlab.ui.control.Label
        Batinthefront                   matlab.ui.control.EditField
        SessionEditFieldLabel           matlab.ui.control.Label
        SessionNum                      matlab.ui.control.NumericEditField
        SessionTypeEditFieldLabel       matlab.ui.control.Label
        SessionType                     matlab.ui.control.EditField
        CommentBox                      matlab.ui.control.TextArea
        SubmitcommentsButton            matlab.ui.control.Button
        TaskPanel                       matlab.ui.container.Panel
        TaskSwitch                      matlab.ui.control.RockerSwitch
        TaskDropDown                    matlab.ui.control.DropDown
        Lamp                            matlab.ui.control.Lamp
        Status_label                    matlab.ui.control.Label
        NooutputCheckBox                matlab.ui.control.CheckBox
        TransceiverSyncCheckBox         matlab.ui.control.CheckBox
        QUITButton                      matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.TabGroup
        SessionTab                      matlab.ui.container.Tab
        DurationhoursEditFieldLabel     matlab.ui.control.Label
        DurationhoursEditField          matlab.ui.control.NumericEditField
        AutomaticoffafterCheckBox       matlab.ui.control.CheckBox
        EnforcesleephoursCheckBox       matlab.ui.control.CheckBox
        FromEditFieldLabel              matlab.ui.control.Label
        SleepTimeH                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        SleepTMin                       matlab.ui.control.NumericEditField
        ForEditFieldLabel               matlab.ui.control.Label
        SleepDur                        matlab.ui.control.NumericEditField
        hrsLabel                        matlab.ui.control.Label
        SounddetectionTab               matlab.ui.container.Tab
        SoundCardLabel                  matlab.ui.control.Label
        ChannelListBoxLabel             matlab.ui.control.Label
        SoundDetecChannel               matlab.ui.control.ListBox
        TriggerthresholdsLabel          matlab.ui.control.Label
        SoundbufferdurationsecSpinnerLabel  matlab.ui.control.Label
        SoundbufferdurationsecSpinner   matlab.ui.control.Spinner
        DurationmsEditFieldLabel        matlab.ui.control.Label
        DurationmsEditField             matlab.ui.control.NumericEditField
        AmplitudedBEditFieldLabel       matlab.ui.control.Label
        AmplitudedBEditField            matlab.ui.control.NumericEditField
        HighpassfilterkHzLabel          matlab.ui.control.Label
        HighpassfilterkHzEditField      matlab.ui.control.NumericEditField
        RMSEditFieldLabel               matlab.ui.control.Label
        RMSEditField                    matlab.ui.control.NumericEditField
        FiledurationminSpinnerLabel     matlab.ui.control.Label
        FiledurationminSpinner          matlab.ui.control.Spinner
        LowpassfilterkHzEditFieldLabel  matlab.ui.control.Label
        LowpassfilterkHzEditField       matlab.ui.control.NumericEditField
        LowPassCheckBox                 matlab.ui.control.CheckBox
        HighPassCheckBox                matlab.ui.control.CheckBox
        MotuGaindBEditFieldLabel        matlab.ui.control.Label
        MotuGaindBEditField             matlab.ui.control.NumericEditField
        PlaybackTab                     matlab.ui.container.Tab
        SoundCardLabel_2                matlab.ui.control.Label
        ChannelListBoxLabel_2           matlab.ui.control.Label
        PlaybackChannel                 matlab.ui.control.ListBox
        SelectListBoxLabel              matlab.ui.control.Label
        PlayListBox                     matlab.ui.control.ListBox
        SoundfilesLabel                 matlab.ui.control.Label
        UpdatefilelistButton            matlab.ui.control.Button
        RepetitionEditFieldLabel        matlab.ui.control.Label
        RepetitionEditField             matlab.ui.control.NumericEditField
        MinIntercallintervalsEditFieldLabel  matlab.ui.control.Label
        MinIntercallintervalsEditField  matlab.ui.control.NumericEditField
        MaxIntercallintervalsEditFieldLabel  matlab.ui.control.Label
        MaxIntercallintervalsEditField  matlab.ui.control.NumericEditField
        CalibratePlayBackGainButton     matlab.ui.control.Button
        CurrentRepEditFieldLabel        matlab.ui.control.Label
        CurrentRepEditField             matlab.ui.control.NumericEditField
        PlayingEditFieldLabel           matlab.ui.control.Label
        PlayingEditField                matlab.ui.control.EditField
        CompensationCheckBox            matlab.ui.control.CheckBox
        PlaybackTaskparametersLabel     matlab.ui.control.Label
        RewardTab                       matlab.ui.container.Tab
        MotorspeedSpinnerLabel          matlab.ui.control.Label
        MotorspeedSpinner               matlab.ui.control.Spinner
        MaxrewardsEditFieldLabel        matlab.ui.control.Label
        MaxrewardsEditField             matlab.ui.control.NumericEditField
        RewarddurationsEditFieldLabel   matlab.ui.control.Label
        RewarddurationsEditField        matlab.ui.control.NumericEditField
        TimedelaytoretrieverewardsEditFieldLabel  matlab.ui.control.Label
        TimedelaytoretrieverewardsEditField  matlab.ui.control.NumericEditField
        CurrentrewardsdeliveredEditFieldLabel  matlab.ui.control.Label
        CurrentrewardsdeliveredEditField  matlab.ui.control.NumericEditField
        LEDCueonDropDownLabel           matlab.ui.control.Label
        LEDCueonDropDown                matlab.ui.control.DropDown
        Delay2retrieverewardStrictFlexDropDown  matlab.ui.control.DropDown
        TimeDelaytoVocalizesfortaskInputBeamVocEditFieldLabel  matlab.ui.control.Label
        TimeDelaytoVocalizesfortaskInputBeamVocEditField  matlab.ui.control.NumericEditField
        RewardPlayBackCheckBox          matlab.ui.control.CheckBox
        PunishmentTab                   matlab.ui.container.Tab
        DurationsecEditFieldLabel       matlab.ui.control.Label
        TimeOutDuration                 matlab.ui.control.NumericEditField
        TimeoutforignoringthecueCheckBox  matlab.ui.control.CheckBox
        unsuccessfulrewardretrievaltotriggerDuncecapEditFieldLabel  matlab.ui.control.Label
        unsuccessfulrewardretrievaltotriggerDuncecapEditField  matlab.ui.control.NumericEditField
        DuncecapforignoringthecueCheckBox  matlab.ui.control.CheckBox
        DurationsecEditFieldLabel_2     matlab.ui.control.Label
        DunceCapDuration                matlab.ui.control.NumericEditField
        currentunsuccessfulrewardretrievalEditFieldLabel  matlab.ui.control.Label
        currentunsuccessfulrewardretrievalEditField  matlab.ui.control.NumericEditField
        ResultsTab                      matlab.ui.container.Tab
        SelectsessionLabel              matlab.ui.control.Label
        SelectDataDropDown              matlab.ui.control.DropDown
        UpdateDataListButton            matlab.ui.control.Button
        UpdategraphButton               matlab.ui.control.Button
        BackReLabel                     matlab.ui.control.Label
        FrontReLabel                    matlab.ui.control.Label
        BackVocLabel                    matlab.ui.control.Label
        FrontVocLabel                   matlab.ui.control.Label
        XminEditFieldLabel              matlab.ui.control.Label
        XminEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        SoundtimeDropDownLabel          matlab.ui.control.Label
        SoundtimeDropDown               matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        ParametersLabel                 matlab.ui.control.Label
        BoxmanualcontrolPanel           matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        FwdButton                       matlab.ui.control.Button
        BwdButton                       matlab.ui.control.Button
        MotorLabel                      matlab.ui.control.Label
        LEDLabel                        matlab.ui.control.Label
        TestCueButton                   matlab.ui.control.Button
        TestTimeoutButton               matlab.ui.control.Button
        SpeakerLabel                    matlab.ui.control.Label
        MicLabel                        matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        Detect                          matlab.ui.control.StateButton
        MicLamp                         matlab.ui.control.Lamp
        BeamBreakersLabel               matlab.ui.control.Label
        TestTriggerFrontButton          matlab.ui.control.Button
        TestTriggerBackButton           matlab.ui.control.Button
        TestFoodFrontButton             matlab.ui.control.Button
        TestFoodBackButton              matlab.ui.control.Button
        TriggersLabel                   matlab.ui.control.Label
        FoodportsLabel                  matlab.ui.control.Label
        BBLamp                          matlab.ui.control.Lamp
        BoxIDLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileTimer % % timer that automatically regularly pauses soundmexpro recording and playing and changes the name of the name of the file recording the input channel
        GraphData % Will contain data of the Results tab Grap
        Timer_AutoOff % Timer that stop the task after the requested duration
    end
    
    methods (Access = private)
        function change_output_rec_wav(app, obj, event, string_arg)
            fprintf('%s at %s\n', string_arg,datestr(now, 'yyyymmddTHHMMSSFFF'))
            global Box
            ChangeFile = tic;
            Box.Task.Output.Sequence = Box.Task.Output.Sequence +1; % this the counter that keeps track of the number of recorded wav files
            MicInd = strfind(Box.Task.Output.Filename_mic, 'mic');
            Filename_temp = Box.Task.Output.Filename_mic(1:MicInd+2);
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            else
                if 1~=soundmexpro('recpause', 'value', ones(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            end
            
            %Set filename for the microphones inputs
            Box.Task.Output.Filename_mic = sprintf('%s%d_%d.wav', Filename_temp, 1, Box.Task.Output.Sequence);
            soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_mic, 'channel', 'Mic');
            
            % Set filename for the TTL input channel and load the next ttl pulse file
            if app.TransceiverSyncCheckBox.Value
                TTLInd = strfind(Box.Task.Output.Filename_ttl, 'ttl');
                Filename_temp = Box.Task.Output.Filename_ttl(1:TTLInd+3);
                Box.Task.Output.Filename_ttl = sprintf('%s%d.wav', Filename_temp, Box.Task.Output.Sequence);
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_ttl, 'channel', 'TTL_in');
                if 1~=soundmexpro('loadfile', 'filename', fullfile(Box.Task.TTLWavDir, sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)), 'track', {'TTTL_out_mirror', 'TTTL_out_transceivers'})
                    error('Unable to load ttl wav pulse %s callback function change_output_rec_wav of the timer app.FileTimer', fullfile(Box.Task.TTLWavDir,sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)));
                end
            end
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('error setting recpause in callback function change_output_rec_wav of the timer app.FileTimer %s\n', datestr(now,30));
                end
            end
            StartTimeStamp = toc(Box.Task.TimerTask);
            StartDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
            if ~app.NooutputCheckBox.Value
                % save the time and sample stamp to file
                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fileevent, StartDateTime,StartTimeStamp, NaN,NaN,NaN, toc(ChangeFile));
            end
            toc(ChangeFile)
        end
        
    end
    
    methods (Access = public)
        
        function Box = init_arduino(app, Box)
            %% input arduino port connection params given by Windows and the board name
            if Box.ID == 5
                port = 'COM6';
            elseif (Box.ID == 7) || (Box.ID == 3) || (Box.ID == 4) || (Box.ID == 6) || (Box.ID == 8) || (Box.ID == 2)
                port = 'COM3';
            elseif Box.ID == 1
                port = 'COM4';
            elseif Box.ID == 9
                port = 'COM5';
            end
            
            % Setting the board name
            board = 'uno';
            
            %% Initializing the Arduino and configure pins
            Box.Arduino = arduino(port, board, 'Libraries', 'Adafruit\MotorShieldV2');
            shield = addon(Box.Arduino, 'Adafruit\MotorShieldV2');
            
            % Setting the pin of LEDs and Beam breakers on the Arduino
            Box.LEDTimeOut = 'D13'; %timeout white LED pin
            Box.LEDCue = 'D10'; %cue green LED pin
            Box.LEDPin3 = 'D12'; %reward LED pin
            Box.BeamInputFront = 'D2'; %trial start beam break input pin for front bat
            Box.BeamFoodFront = 'D4'; %food port beam break input pin for front bat
            Box.BeamInputBack = 'D6'; %trial beam break input pin for back bat
            Box.BeamFoodBack = 'D8'; %food port beam break input pin for back bat
            
            configurePin(Box.Arduino, Box.BeamInputFront, 'pullup');
            configurePin(Box.Arduino, Box.BeamFoodFront, 'pullup');
            configurePin(Box.Arduino,Box.BeamInputBack, 'pullup');
            configurePin(Box.Arduino, Box.BeamFoodBack, 'pullup');
            configurePin(Box.Arduino, Box.LEDTimeOut, 'DigitalOutput');
            configurePin(Box.Arduino, Box.LEDCue, 'DigitalOutput');
            configurePin(Box.Arduino, Box.LEDPin3, 'DigitalOutput');
            
            % Make sure LEDs are switched off
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1); %start LEDpin at high
            writeDigitalPin(Box.Arduino, Box.LEDCue, 0); %start LEDpin at low
            writeDigitalPin(Box.Arduino, Box.LEDPin3, 0); %start LEDpin at low
            
            %% Initializing the motor
            Box.dcm = dcmotor(shield, 1); % Defining the syringe motor
            Box.dcm.Speed = app.MotorspeedSpinner.Value; % Get the speed of the syringe motor
            
        end
        
        function [Box] = init_soundmex(app, Box)
            %% Initialize recording and playback channels, audio saving paths, AmpTracks and audio buffer filename
            % When channels are set up as vectors, soundmexPro will pick which ever
            % from those has signal.
            % To see possible configuration on the MOTU before initialization, type:
            % soundmexpro_showdevs
            % Channels available on MOTU 896mk3:
            % soundmex output channels 0 and 1 correspond to Main Out on sound card
            % soundmex output channels 2 and 3 correspond to Analog out 1 and 2 on sound card
            % soundmex output channels 4 and 5 correspond to Analog out 3 and 4 on sound card
            % soundmex output channels 6 and 7 correspond to Analog out 5 and 6 on sound card
            % soundmex output channels 8 and 9 correspond to Analog out 7 and 8 on sound card
            % soundmex input channels 0 and 1 correspond to Analog in 1 and 2 on sound card
            % soundmex input channels 2 and 3 correspond to Analog in 3 and 4 on sound card
            % soundmex input channels 4 and 5 correspond to Analog in 5 and 6 on sound card
            % soundmex input channels 6 and 7 correspond to Analog in 7 and 8 on sound card
            
            if (Box.ID == 3) || (Box.ID == 4) || (Box.ID == 2) || (Box.ID == 5)
                Box.Channels.Mic = 0; % or (0:1)
                Box.Channels.Play = 2;
                Box.Channels.PhonesL = 0;
                Box.Channels.PhonesR = 1;
                Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
                Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
                Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output
            elseif (Box.ID == 6) || (Box.ID == 8) || (Box.ID == 1) || (Box.ID == 7)
                Box.Channels.Mic = 1; % or (0:1)
                Box.Channels.Play = 2;
                Box.Channels.PhonesL = 0;
                Box.Channels.PhonesR = 1;
                Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
                Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
                Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output
            elseif Box.ID == 9
                Box.Channels.Mic = 0; % or (0:1)
                Box.Channels.TTL_in = 7; % Always set the TTL-in as the last analog input on soundcard
                Box.Channels.Play = 2;
                Box.Channels.PhonesL = 0;
                Box.Channels.PhonesR = 1;
                Box.Channels.TTL_out_mirror = 8; % Always set the TTL-out as the one but last analog output on soundcard
                Box.Channels.TTL_out_transceivers = 9; % Always set the TTL-out as the last analog output on soundcard
            end
            Curr_path = pwd;
            filepartitions=strfind(Curr_path, '\');
            Box.RecPath=sprintf('%s\\Desktop\\bataudio\\autoTrain\\box_%d\\', Curr_path(1:(filepartitions(3)-1)), Box.ID);
            Box.PlayPath=sprintf('%s\\Desktop\\bataudio\\autoTrain\\playback\\', Curr_path(1:(filepartitions(3)-1)));
            
            Box.MicFile=fullfile(Box.RecPath, sprintf('mic%d_box%d.wav', Box.Channels.Mic, Box.ID));
            
            Box.TTLFile = fullfile(Box.RecPath, sprintf('ttl_box%d.wav', Box.ID));
            
            % Set names of the channels for the sound card in the app dropdown menus
            clear soundmexpro % In case it was already created by some previous runs
            ID='MOTU Audio ASIO';
            [~, Outch,Inch]=soundmexpro('getchannels', 'driver', ID);
            app.SoundDetecChannel.Items = Inch;
            app.PlaybackChannel.Items = Outch;
            app.SoundDetecChannel.Value = app.SoundDetecChannel.Items(Box.Channels.Mic+1);
            app.PlaybackChannel.Value = app.PlaybackChannel.Items(Box.Channels.Play+1);
            
            % Set the gain for playing files
            GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain*'));
            if isempty(GainFile)
                fprintf(1,'Warning!!! No Gain file for this set-up the default value (1) will be used for playing sound files with soundmexpro\n')
                Box.PlayBackGain = 1;
            elseif app.CompensationCheckBox.Value
                GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain_Compensated.mat'));
                if isempty(GainFile)
                    fprintf(1,'Warning!!! No Gain file compensated for this set up, the non compensated one is used\n');
                    GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain.mat'));
                end
                Gain = load(fullfile(GainFile.folder, GainFile.name));
                Box.PlayBackGain = Gain.Gain_in;
            else
                GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain.mat'));
                if isempty(GainFile)
                    fprintf(1,'Warning!!! No Gain file NOT compensated for this set up, the compensated one is used\n');
                    GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain_Compensated.mat'));
                end
                Gain = load(fullfile(GainFile.folder, GainFile.name));
                Box.PlayBackGain = Gain.Gain_in;
            end
            
            %% set default sample frequency
            Box.SoundCard.fs=192000;
            
            %% initialize soundmexpro
            nbufs=2; %Set the # of buffers for recording
            soundmexpro('init','driver',ID,'samplerate',Box.SoundCard.fs,'input',[Box.Channels.Mic Box.Channels.TTL_in],...
                'output',[Box.Channels.PhonesL Box.Channels.PhonesR Box.Channels.Play Box.Channels.TTL_out_mirror Box.Channels.TTL_out_transceivers],'track',length([Box.Channels.PhonesL Box.Channels.PhonesR Box.Channels.Play Box.Channels.TTL_out_mirror Box.Channels.TTL_out_transceivers]),'numbufs',nbufs);
            % Note that channels indexing starts at 0 in soundmex but 1 everywhere else (on motu, matlab...)
            soundmexpro('show')
            
            %% map tracks to output channels
            % new mapping, here the indices in the vector refer to the new indices of output channels in soundmexpro irrespective of the indices of the soundcard output channels
            % Setting one track per output channel
            if 1~= soundmexpro('trackmap', ...
                    'track', 0:(length([Box.Channels.PhonesL Box.Channels.PhonesR Box.Channels.Play Box.Channels.TTL_out_mirror Box.Channels.TTL_out_transceivers])-1))
                error('error setting trackmap in init_soundmex %s\n', datestr(now,30));
            end
            
            % Name output channels
            if 1~= soundmexpro('channelname', 'output', [0 1 2 3 4], 'name', {'PhoneL', 'PhoneR', 'Speaker', 'TTL_out_mirror', 'TTL_out_transceivers'})
                error('could not set up out put channel names in init_soundmex\n')
            end
            
            % Name input channels
            if 1~= soundmexpro('recname', 'input', [0 1], 'name', {'Mic', 'TTL_in'})
                error('Could not set up input channel names in init_soundmex\n')
            end
            
            % Name tracks
            if 1~= soundmexpro('trackname', 'track', [0 1 2 3 4], 'name', {'TPhoneL', 'TPhoneR','TSpeaker', 'TTTL_out_mirror', 'TTTL_out_transceivers'})
                error('Could not set up input channel names in init_soundmex\n')
            end
            
            % Map the input Mic to Phones
            if 1~= soundmexpro('iostatus', 'input', {'Mic','Mic'}, 'track', {'TPhoneL', 'TPhoneR'})
                error('Could not map input Mic channel to Phones in init_soundmex\n')
            end
            
            % Type the following command to see the track mapping in soundmexpro:
            % soundmexpro_showcfg
            
            %% Disabling writing to the recording file for all input channels
            if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                error('error setting recpause in init_soundmex %s\n', datestr(now,30));
            end
            
            %% Set default filename for the Mic input channel
            soundmexpro('recfilename', 'filename', Box.MicFile, 'channel', {'Mic'});
            
            
            %% Set default filename for the TTL input channel
            soundmexpro('recfilename', 'filename', Box.TTLFile, 'channel', {'TTL_in'}); % The TTL channel is always the last one irrespective of the number of Mic Channels
            
            %% List the files in the playback folder
            Files = dir(fullfile(Box.PlayPath, '*.wav'));
            app.PlayListBox.ItemsData = cell(length(Files),1);
            for ff=1:length(Files)
                app.PlayListBox.Items{ff} = Files(ff).name;
                app.PlayListBox.ItemsData{ff} = fullfile(Box.PlayPath, Files(ff).name);
            end
            
        end
        
        
        function [Success, Stamp, Wave] = soundDetect(app,Box,NumBufClip, Filter, RmsThresh)
            Success=0;
            Stamp=[];
            Wave=[];
            %% Querry the number of cliping buffers from last reset in the input channels
            [~, ~ , Clipin, ~]=soundmexpro('clipcount');
            if 1~=soundmexpro('resetclipcount') % reset all clip counters to zero
                error('Failed to reset clipcount in SoundDetect')
            end
            %% Clipping threshlods in number of samples
            ClipSamp = app.DurationmsEditField.Value/1000 * Box.SoundCard.fs;
            
            %% CHECK the sound extract that hit the clipping duration threshold on the main first recording channel
            if Clipin(1)>=NumBufClip
                callOnlyTrigger = datestr(now, 'yyyymmddTHHMMSSFFF');
                [~,rectrg,Stamp_local]=soundmexpro('recgetdata','channel','Mic'); % get sound data
                if app.LowPassCheckBox.Value || app.HighPassCheckBox.Value
                    filt_trg=filtfilt(Filter.B,Filter.A,rectrg(end-ClipSamp:end,1)); % apply the frequency filter only on the sound segment that hit the minimum # of clipping buffers
                else
                    filt_trg = rectrg(end-ClipSamp:end,1);
                end
                H=rms(filt_trg); % calculate RMS of the filtered sound extract
                if H<RmsThresh % Not reaching RMS threshold, the sound extract is discarded. may need to increase if still get cage noise
                    %                     fprintf('rms of frequency filtered sound trigger NOT reaching threshold %i >>>>> %s\nTriggered at %s\n', H, datestr(now,'HH:MM:SS'),callOnlyTrigger);
                else
                    Success=1;
                    if isfield(Box, 'Task')
                        Stamp= sprintf('%d_%d', Box.Task.Output.Sequence,Stamp_local+length(rectrg)-ClipSamp-1);
                    end
                    fprintf('Sound reaching thresholds! >>> RMS=%.4f\nRegistered at %s\n Triggered at %s\n', H, datestr(now,'yyyymmddTHHMMSSFFF'),callOnlyTrigger);
                    Wave = rectrg(end-ClipSamp:end,1); % sound snippet returned by the function that reached all detection requirements
                end
            end
        end
        
        function [NumBufClip,Filter,RmsThresh] = set_sound_detection_parameters(app, Box)
            % Get threshold in terms of amplitude
            AmpThresh = app.AmplitudedBEditField.Value;%-35;
            % set threshold in terms of clipping threshold on soundmex
            if 1 ~= soundmexpro('clipthreshold','type','input','value',...
                    10^(AmpThresh/20))
                error('error setting clipthreshold');
            end
            
            % get duration threshold from app NumBufClip IS THE NUMBER OF BUFFERS
            % EXPECTED TO BE CLIPPING FOR A SOUND REACHING AMPLITUDE TRHESHOLD
            % FOR THE DURATION DURTHRESH
            DurThresh=app.DurationmsEditField.Value/1000;% 5ms;
            % get asio properties
            [~,~,Bufsiz]=soundmexpro('getproperties');
            NumBufClip=round(DurThresh*Box.SoundCard.fs/Bufsiz); % make proper # of buffers (threshdur & fs dependent)
            
            % Set the frequency filter(s) that is (are) applied on the sound extract candidate
            if app.HighPassCheckBox.Value && ~app.LowPassCheckBox.Value % Only high pass filter requested
                % get the frequency threshold from the app
                FreqThresh=app.HighpassfilterkHzEditField.Value;
                % design a 4th order high pass Butterworth filter with cutoff
                % frequency at freqThresh
                [Filter.B,Filter.A]=butter(4,2*FreqThresh*1000/Box.SoundCard.fs,'high');
            elseif ~app.HighPassCheckBox.Value && app.LowPassCheckBox.Value % Only low pass filter requested
                % get the frequency threshold from the app
                FreqThresh=app.LowpassfilterkHzEditField.Value;
                % design a 4th order high pass Butterworth filter with cutoff
                % frequency at freqThresh
                [Filter.B,Filter.A]=butter(4,2*FreqThresh*1000/Box.SoundCard.fs,'low');
            elseif app.HighPassCheckBox.Value && app.LowPassCheckBox.Value % band pass filter requested
                % get the frequency threshold from the app
                FreqThresh=[app.HighpassfilterkHzEditField.Value app.LowpassfilterkHzEditField.Value];
                % design a 4th order high pass Butterworth filter with cutoff
                % frequency at freqThresh
                [Filter.B,Filter.A]=butter(2,2*FreqThresh*1000/Box.SoundCard.fs, 'bandpass');
            else
                Filter = [];
            end
            
            if app.LowPassCheckBox.Value
                
            else
                Filter.Low.A=[];
                Filter.Low.B=[];
            end
            
            %Set the RMS threshold that the filtered sound extract candidate
            %should reach to be kept as a vocalization trigger.
            RmsThresh=app.RMSEditField.Value;
            
            %Set the duration of the sound sample that should be recorded upon detection (value taken from app)
            % set duration of the record buffer for the function recgetdata
            % Note that is buffer is different from the internal buffer sound
            % card. This RecBuf is the length of the sound vector that should
            % be retrieve by soundmexpro when the function recgetdata is called
            % get the desired duration from the app
            if app.SoundbufferdurationsecSpinner.Value<=app.DurationmsEditField.Value/1000
                error('Duration of sound sample that should be recorded upon detection\nis smaller than the duration of the sound extract on which\nthe threshold for detection was calculated:\nParameters-> Sound detection: sound extract duration <= Trigger threshold duration\nIt needs to be larger!!!\n');
            end
            %         if app.SoundbufferdurationsecSpinner<=recShift
            %             error('Duration of sound sample that should be recorded upon detection\nis smaller than the shift used to postpone sound recording after sound detection threshold is hit:\n(recDur<=recShift)\nIt needs to be larger!!!\n');
            %         end
            if 1 ~= soundmexpro( 'recbufsize', 'value', app.SoundbufferdurationsecSpinner.Value*Box.SoundCard.fs)
                error(['error calling ''recbufsize''' error_loc(dbstack)]);
            end
        end
        
        function [] = findgainsoundmex(app, Box,In,Fs, Mic, SP, Gain_in, Compensation)
            
            % Rectify the signal to find Input max value
            MaxIn = max(abs(In));
            if Compensation
                % set the frequency limit, sample frequency for the butterworth filter and calculate the filer
                Fl=0.5e3;
                Fh=90e3;
                [B,A]=butter(8,2.*[Fl./Fs Fh./Fs]);
                % compensate for the Microphone
                Out=filter(Mic.irc,1,In);
                % compensate for the speaker
                Out = filter(SP.irc,1,Out);
                % Apply butter worth filter
                Out=filter(B,A,Out);
                % Scale the signal between -0.8 and 0.8
                Out=Out./max(abs(Out)).*0.8;
                % Apply a sin wav gating of 10ms to the sound
                wds=0.01; % gate duration in s
                npts=length(Out);
                f=1/(2*wds);
                t=0:1/Fs:2*wds-1/Fs;
                m=(1+sin(2*pi*t*f-pi/2))/2;
                mpts=length(m);
                if mpts>npts
                    error('window too long for this stimulus!');
                end
                Out(1:floor(mpts/2)) = Out(1:floor(mpts/2)) .* m(1:floor(mpts/2))';
                Out(npts-ceil(mpts/2):npts)=Out(npts-ceil(mpts/2):npts).*m(mpts-ceil(mpts/2):mpts)';
            else
                Out=In;
            end
            
            % Start recording from soundmexpro
            if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                error('Soundmexpro cannot resume recording using recpause\n');
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % Play sound
            soundmexpro('loadmem', 'data', Out, 'track', 'TSpeaker', 'loopcount',1, 'gain',Gain_in);
            pause(length(Out)/Fs)
            
            % Stop recording and playing
            if 1 ~= soundmexpro('stop')
                error('error stop recording and playing');
            end
            % Stop writing to the recording files for all input channels
            if 1~=soundmexpro('recpause', 'value', ones(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                error('error setting recpause\n');
            end
            
            % load the recorded file
            [In2,~] = audioread(Box.MicFile);
            % Rectify the signal to find Input max value
            MaxIn2 = max(abs(In2));
            % Check if sound played at the same volume
            if round(MaxIn2*1000) == round(MaxIn*1000)
                fprintf(1,'Gain adjusted to %f! MaxIn = %d and MaxIn2 = %d\n', Gain_in, round(MaxIn*1000)/1000, round(MaxIn2*1000)/1000);
                if Compensation
                    File_out = fullfile(Box.RecPath,'SoundMexPro_Gain_Compensated.mat');
                else
                    File_out = fullfile(Box.RecPath,'SoundMexPro_Gain.mat');
                end
                fprintf(1,'Saving value of gain for this box under %s\n',File_out)
                save(File_out, 'Gain_in');
            elseif MaxIn2>MaxIn
                Gain_new = Gain_in - Gain_in*0.001;
                fprintf(1,'MaxIn = %.4f and MaxIn2 = %.4f\nGain to high adjusted to %f! \n', MaxIn, MaxIn2,Gain_new);
                findgainsoundmex(app, Box, In,Fs, Mic, SP, Gain_new, Compensation);
            elseif MaxIn2<MaxIn
                Gain_new = Gain_in + Gain_in*0.001;
                fprintf(1,'MaxIn = %.4f and MaxIn2 = %.4f\nGain to low adjusted to %f! \n', MaxIn, MaxIn2,Gain_new);
                findgainsoundmex(app, Box, In,Fs, Mic, SP, Gain_new, Compensation);
            end
        end
        
    end
    
    methods (Access = private)
        
        function results = disable_sound_detection_inputs(app)
            app.SoundDetecChannel.Enable = 'off';
            app.SoundbufferdurationsecSpinner.Enable = 'off';
            app.DurationmsEditField.Enable = 'off';
            app.AmplitudedBEditField.Enable = 'off';
            app.HighpassfilterkHzEditField.Enable = 'off';
            app.LowpassfilterkHzEditField.Enable = 'off';
            app.HighPassCheckBox.Enable = 'off';
            app.LowPassCheckBox.Enable = 'off';
            app.RMSEditField.Enable = 'off';
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Enable = 'off';
            results=1;
        end
        
        function results = enable_sound_detection_inputs(app)
            app.SoundDetecChannel.Enable = 'on';
            app.SoundbufferdurationsecSpinner.Enable = 'on';
            app.DurationmsEditField.Enable = 'on';
            app.AmplitudedBEditField.Enable = 'on';
            app.HighpassfilterkHzEditField.Enable = 'on';
            app.LowpassfilterkHzEditField.Enable = 'on';
            app.HighPassCheckBox.Enable = 'on';
            app.LowPassCheckBox.Enable = 'on';
            app.RMSEditField.Enable = 'on';
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Enable = 'on';
            results=1;
        end


        function results = disable_metadata_inputs(app)
            app.Batintheback.Enable = 'off';
            app.Batinthefront.Enable = 'off';
            app.SessionNum.Enable = 'off';
            app.SessionType.Enable = 'off';
            app.SubmitcommentsButton.Enable = 'off';
            results=1;
        end
        
        function results = enable_metadata_inputs(app)
            app.Batintheback.Enable = 'on';
            app.Batinthefront.Enable = 'on';
            app.SessionNum.Enable = 'on';
            app.SessionType.Enable = 'on';
            app.SubmitcommentsButton.Enable = 'on';
            results=1;
        end
        
        function results = disable_session_parameters_inputs(app)
            app.DurationhoursEditField.Enable = 'off';
            app.AutomaticoffafterCheckBox.Enable = 'off';
            app.EnforcesleephoursCheckBox.Enable = 'off';
            app.SleepTimeH.Enable = 'off';
            app.SleepTMin.Enable = 'off';
            app.SleepDur.Enable = 'off';
            results = 1;
        end
        
        function results = enable_session_parameters_inputs(app)
            app.DurationhoursEditField.Enable = 'on';
            app.AutomaticoffafterCheckBox.Enable = 'on';
            app.EnforcesleephoursCheckBox.Enable = 'on';
            app.SleepTimeH.Enable = 'on';
            app.SleepTMin.Enable = 'on';
            app.SleepDur.Enable = 'on';
            results = 1;
        end
        
        function results = disable_playback_parameters_inputs(app)
            app.PlaybackChannel.Enable = 'off';
            app.PlayListBox.Enable = 'off';
            app.UpdatefilelistButton.Enable = 'off';
            app.RepetitionEditField.Enable = 'off';
            app.MinIntercallintervalsEditField.Enable = 'off';
            app.MaxIntercallintervalsEditField.Enable = 'off';
            app.CompensationCheckBox.Enable = 'off';
            app.CalibratePlayBackGainButton.Enable = 'off';
            results = 1;
        end
        
        function results = enable_playback_parameters_inputs(app)
            app.PlaybackChannel.Enable = 'on';
            app.PlayListBox.Enable = 'on';
            app.UpdatefilelistButton.Enable = 'on';
            app.RepetitionEditField.Enable = 'on';
            app.MinIntercallintervalsEditField.Enable = 'on';
            app.MaxIntercallintervalsEditField.Enable = 'on';
            app.CompensationCheckBox.Enable = 'on';
            app.CalibratePlayBackGainButton.Enable = 'on';
            results = 1;
        end
        
        function results = disable_reward_parameters_input(app)
            app.MotorspeedSpinner.Enable = 'off';
            app.MaxrewardsEditField.Enable = 'off';
            app.RewarddurationsEditField.Enable = 'off';
            app.TimedelaytoretrieverewardsEditField.Enable = 'off';
            app.Delay2retrieverewardStrictFlexDropDown.Enable = 'off';
            app.LEDCueonDropDown.Enable = 'off';
            results = 1;
        end
        
        function results = enable_reward_parameters_input(app)
            app.MotorspeedSpinner.Enable = 'on';
            app.MaxrewardsEditField.Enable = 'on';
            app.RewarddurationsEditField.Enable = 'on';
            app.TimedelaytoretrieverewardsEditField.Enable = 'on';
            app.Delay2retrieverewardStrictFlexDropDown.Enable = 'on';
            app.LEDCueonDropDown.Enable = 'on';
            results = 1;
        end
        
        function results =disable_punishment_parameters_input(app)
            app.TimeoutforignoringthecueCheckBox.Enable = 'off';
            app.TimeOutDuration.Enable = 'off';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Enable = 'off';
            app.DuncecapforignoringthecueCheckBox.Enable = 'off';
            app.DunceCapDuration.Enable = 'off';
            results=1;
        end
        
        function results =enable_punishment_parameters_input(app)
            app.TimeoutforignoringthecueCheckBox.Enable = 'on';
            app.TimeOutDuration.Enable = 'on';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Enable = 'on';
            app.DuncecapforignoringthecueCheckBox.Enable = 'on';
            app.DunceCapDuration.Enable = 'on';
            results=1;
        end
        
        function results = disable_manual_control_buttons(app)
            app.ResetButton.Enable = 'off';
            app.FwdButton.Enable = 'off';
            app.BwdButton.Enable = 'off';
            app.TestCueButton.Enable = 'off';
            app.TestTimeoutButton.Enable = 'off';
            app.PlayButton.Enable = 'off';
            app.Detect.Enable = 'off';
            results=1;
        end
        
        function results = enable_manual_control_buttons(app)
            app.ResetButton.Enable = 'on';
            app.FwdButton.Enable = 'on';
            app.BwdButton.Enable = 'on';
            app.TestCueButton.Enable = 'on';
            app.TestTimeoutButton.Enable = 'on';
            app.PlayButton.Enable = 'on';
            app.Detect.Enable = 'on';
            results=1;
            
        end
        
        function init_task(app, TaskType)
            global Box
            % Set up the date
            Today = datestr(now, 'yymmdd_HHMM');
            
            % Set up output wavefile name, parameter file for the task and result file for events' time stamp
            Filename_param = fullfile(Box.RecPath, sprintf('%s%s_%s_%s_param.txt', app.Batintheback.Value(1:2), app.Batinthefront.Value(1:2), Today, TaskType));
            Box.Task.Output.Fid_out_param = fopen(Filename_param, 'wt');
            
            if Box.Task.Output.Fid_out_param==-1
                error('Could not create output file %s\n', Filename_param);
            end
            
            Filename_events = fullfile(Box.RecPath, sprintf('%s%s_%s_%s_events.txt', app.Batintheback.Value(1:2), app.Batinthefront.Value(1:2), Today, TaskType));
            Box.Task.Output.Fid_out_events = fopen(Filename_events, 'wt');
            if Box.Task.Output.Fid_out_events==-1
                error('Could not create output file %s\n', Filename_events);
            end
            if strcmp(TaskType, 'BBTrigger') || strcmp(TaskType, 'BBorVocTrigger')
                fprintf(Box.Task.Output.Fid_out_events, 'DateTime\tTimeStamp(s)\tSampleStamp\tType\tInputBeamFront\tInputBeamBack\tFoodPortFront\tFoodPortBack\tDelay2Reward\n');
                Box.Task.Output.FormatSpec_bbevent = '%s\t%.5f\t%s\tInputBeam\t%d\t%d\t%d\t%d\t%f\n';
                Box.Task.Output.FormatSpec_vocevent = '%s\t%.5f\t%s\tVocalization\t%d\t%d\t%d\t%d\t%f\n';
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\t%s\tChangeFile\tNaN\tNaN\t%d\t%d\t%f\n';
            elseif strcmp(TaskType, 'BBVocTrigger')
                fprintf(Box.Task.Output.Fid_out_events, 'BeamDateTime\tBeamTimeStamp(s)\tVocDateTime\tVocTimeStamp(s)\tSampleStamp\tType\tInputBeamFront\tInputBeamBack\tFoodPortFront\tFoodPortBack\tDelay2Voc\tDelay2Reward\n');
                Box.Task.Output.FormatSpec_bbvocevent = '%s\t%.5f\t%s\t%.5f\t%s\tInputBeamVoc\t%d\t%d\t%d\t%d\t%f\t%f\n';
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\t%s\tNaN\tNaN\tChangeFile\tNaN\tNaN\t%d\t%d\t%f\tNaN\n';
            elseif strcmp(TaskType, 'PBOnly')
                fprintf(Box.Task.Output.Fid_out_events, 'DateTime\tTimeStamp(s)\tStimFile\tType\n');
                Box.Task.Output.FormatSpec_pbevent = '%s\t%.5f\t%s\tPlayBack\t%d\t%d\t%f\n';
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\t%s\tChangeFile\t%d\t%d\t%f\n';
            else
                fprintf(Box.Task.Output.Fid_out_events, 'DateTime\tTimeStamp(s)\tSampleStamp\tType\tFoodPortFront\tFoodPortBack\tDelay2Reward\n');
                Box.Task.Output.FormatSpec_vocevent = '%s\t%.5f\t%s\tVocalization\t%d\t%d\t%f\n';
                Box.Task.Output.FormatSpec_fpevent = '%s\t%.5f\t%s\tFoodPort\t%d\t%d\t%f\n';
                Box.Task.Output.FormatSpec_fileevent = '%s\t%.5f\t%s\tChangeFile\t%d\t%d\t%f\n';
            end
            
            % Reset counters for rewards and punishments
            app.currentunsuccessfulrewardretrievalEditField.Value = 0;
            app.CurrentrewardsdeliveredEditField.Value = 0;
            
            % Set filename for the recording input channel
            Box.Task.Output.Sequence = 1; % this the counter that keeps track of the number of recorded wav files
            Box.Task.Output.Filename_mic = fullfile(Box.RecPath, sprintf('%s%s_%s_%s_mic%d_%d.wav', app.Batintheback.Value(1:2), app.Batinthefront.Value(1:2), Today, TaskType, 1, Box.Task.Output.Sequence));
            soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_mic, 'channel', 'Mic');
            
            
            % Set filename for the TTL input channel
            if app.TransceiverSyncCheckBox.Value
                Box.Task.Output.Filename_ttl = fullfile(Box.RecPath, sprintf('%s%s_%s_%s_ttl_%d.wav', app.Batintheback.Value(1:2), app.Batinthefront.Value(1:2), Today, TaskType, Box.Task.Output.Sequence));
                soundmexpro('recfilename', 'filename', Box.Task.Output.Filename_ttl, 'channel', 'TTL_in');
            end
            
            % Create a timer that automatically regularly pauses soundmexpro recording and playing and changes the names of the file recording the input channel
            app.FileTimer = timer;
            app.FileTimer.Name = 'file-timer';
            app.FileTimer.ExecutionMode = 'fixedRate';
            app.FileTimer.Period = 60*app.FiledurationminSpinner.Value;
            app.FileTimer.StartDelay = 60*app.FiledurationminSpinner.Value;
            app.FileTimer.TimerFcn = {@app.change_output_rec_wav, 'File switch\n'};
            
            % Create folder for the output wavfiles (sound snippets that reached tresholds and that are saved by matlab)
            if ~strcmp(TaskType, 'PBOnly')
                Box.Task.Output.SnipDir = fullfile(Box.RecPath,sprintf('%s%s_%s_%s_snippets', app.Batintheback.Value(1:2), app.Batinthefront.Value(1:2), Today,TaskType));
                if ~(exist(Box.Task.Output.SnipDir, 'dir')==7)
                    mkdir(Box.Task.Output.SnipDir);
                end
                Box.Task.Output.SnipFile = sprintf('%s%s_%s_%s_snipfile', app.Batintheback.Value(1:2), app.Batinthefront.Value(1:2), Today, TaskType);
            end
            
            % Get ready TTL playback files
            if app.TransceiverSyncCheckBox.Value
                Box.Task.TTLWavDir = fullfile(Box.RecPath,'TTL_Wav');
                if ~(exist(Box.Task.TTLWavDir, 'dir')==7)
                    mkdir(Box.Task.TTLWavDir);
                end
                % Determine how long the task is, default is set to 4 hours
                if app.AutomaticoffafterCheckBox.Value
                    TaskDuration = app.DurationhoursEditField.Value;
                else
                    TaskDuration = 4;
                end
                % default value for TTL pulses file is the same as the duration of output recording files
                
                % find out if the last TTl wav that were created had the same parameters and are still here
                CreateTTL = 1;
                %Get the directory contents
                Dirc = dir(fullfile(Box.Task.TTLWavDir, '*unique_ttl_params.mat'));
                %I contains the index to the biggest number which is the latest file
                [~,I] = max([Dirc(:).datenum]);
                if ~isempty(I)
                    Lastparam = load(fullfile(Box.Task.TTLWavDir,Dirc(I).name));
                    if (Lastparam.TotalDuration==TaskDuration) && (Lastparam.FileDuration==app.FiledurationminSpinner.Value) && (Lastparam.FS == Box.SoundCard.fs) && (strcmp(Lastparam.TTLCode, 'Value'))
                        % Same parameters as today. Let's check that files are still there
                        TotalDuration_samp = Lastparam.TotalDuration*3600*Lastparam.FS;
                        FileDuration_samp = Lastparam.FileDuration*60*Lastparam.FS; % # samples on an individual playback files
                        N_files = ceil(TotalDuration_samp/FileDuration_samp);
                        LastTTL = dir(fullfile(Box.Task.TTLWavDir, 'unique_ttl*.wav'));
                        if length(LastTTL)==N_files
                            Today = datestr(now, 'yymmdd_HHMM');
                            save(fullfile(Box.Task.TTLWavDir, [Today 'unique_ttl_params.mat']),'-struct', 'Lastparam');
                            CreateTTL=0;
                        end
                    end
                end
                if CreateTTL
                    Current_dir = pwd;
                    Code_Ind = strfind(Current_dir, 'Code');
                    Path2TTLfolder = [Current_dir(1:(Code_Ind + 4)) '/neurobat-hardware-alignment'];
                    addpath(genpath(Path2TTLfolder));
                    generate_zero_playback_files('TotalDuration', TaskDuration,'FileDuration',app.FiledurationminSpinner.Value,'FS',Box.SoundCard.fs, 'TTLCode', 'Value', 'Path', Box.Task.TTLWavDir)
                end
                if 1~=soundmexpro('loadfile', 'filename', fullfile(Box.Task.TTLWavDir, sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)), 'track', {'TTTL_out_mirror', 'TTTL_out_transceivers'})
                    error('Unable to load ttl wav pulse %s in init_task',fullfile(Box.Task.TTLWavDir, sprintf('unique_ttl%d.wav',Box.Task.Output.Sequence)));
                end
            end
            
            % Save parameter values to file:
            if app.AutomaticoffafterCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Automatic off requested after: %.2f hours\n', app.DurationhoursEditField.Value);
            else
                fprintf(Box.Task.Output.Fid_out_param, 'Automatic off not requested\n');
            end
            if app.EnforcesleephoursCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours requested\n');
            else
                fprintf(Box.Task.Output.Fid_out_param, 'Automatic sleep hours not requested\n');
            end
            fprintf(Box.Task.Output.Fid_out_param, 'Input Channel: %s\n', cell2mat(app.SoundDetecChannel.Value));
            fprintf(Box.Task.Output.Fid_out_param, 'Motu gain: %d dB\n', app.MotuGaindBEditField.Value);
            fprintf(Box.Task.Output.Fid_out_param, 'Mic recording saved to: %s\n', Box.Task.Output.Filename_mic);
            fprintf(Box.Task.Output.Fid_out_param, 'Events saved to: %s\n', Filename_events);
            if ~strcmp(TaskType, 'PBOnly')
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection amplitude threshold: %.2f dB\n', app.AmplitudedBEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection duration threshold: %.2f ms\n', app.DurationmsEditField.Value);
                if app.HighPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: %.2f Hz\n', app.HighpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection high-pass filter frequency threshold: None\n');
                end
                if app.LowPassCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: %.2f Hz\n', app.LowpassfilterkHzEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection low-pass filter frequency threshold: None\n');
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Sound detection RMS threshold: %.3f\n', app.RMSEditField.Value);
                if strcmp(TaskType, 'BBVocTrigger')
                    fprintf(Box.Task.Output.Fid_out_param, 'Sound detection delay to vocalize after beam break: %.2f sec\n', app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Value);
                end
            end
            if ~strcmp(TaskType, 'PBOnly') && ~strcmp(TaskType, 'RecOnly')
                fprintf(Box.Task.Output.Fid_out_param, 'Time delay to get reward: %f s\n', app.TimedelaytoretrieverewardsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Delay to get reward: %s\n', app.Delay2retrieverewardStrictFlexDropDown.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Cue  LED mode: %s\n', app.LEDCueonDropDown.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Reward duration: %f s\n', app.RewarddurationsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Motor speed: %f\n', app.MotorspeedSpinner.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Rewarding playback: %f\n', app.RewardPlayBackCheckBox.Value);
                if app.RewardPlayBackCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Playback file: %s\n',app.PlayListBox.Value{1});
                end
                if app.TimeoutforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue requested of: %f s\n', app.TimeOutDuration.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Time out for ignoring the cue not requested\n');
                end
                if app.DuncecapforignoringthecueCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue requested of: %f s after %d unsuccessful trials\n', app.DunceCapDuration.Value, app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value);
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Dunce cap for ignoring the cue not requested\n');
                end
            end
            if app.TransceiverSyncCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal requested\n');
            else
                fprintf(Box.Task.Output.Fid_out_param, 'Synchronization with TTL signal not requested\n');
            end
            if strcmp(TaskType, 'PBOnly')
                fprintf(Box.Task.Output.Fid_out_param, 'Play-Back repetitions: %d\n', app.RepetitionEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Min Inter-call interval: %f s\n', app.MinIntercallintervalsEditField.Value);
                fprintf(Box.Task.Output.Fid_out_param, 'Max Inter-call interval: %f s\n', app.MaxIntercallintervalsEditField.Value);
                if app.CompensationCheckBox.Value
                    fprintf(Box.Task.Output.Fid_out_param, 'Using gain calculated for compensated files\n');
                else
                    fprintf(Box.Task.Output.Fid_out_param, 'Using gain calculated for non-compensated files\n');
                end
            end
            
            start(app.FileTimer);
        end
        
        function stop_task(app, obj, event)
            global Box
            % End the task
            % Stop and delete from memory the timer that automatically changes output files
            stop(app.FileTimer);
            delete(app.FileTimer);
            % Stop recording and playing
            if 1 ~= soundmexpro('stop')
                error('error stop recording and playing in stop_task %s\n', datestr(now,30));
            end
            % Stop writing to the recording files for all input channels
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', ones(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('error setting recpause in stop_task %s\n', datestr(now,30));
                end
            else
                if 1~=soundmexpro('recpause', 'value', ones(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('error setting recpause in stop_task %s\n', datestr(now,30));
                end
            end
            
            % Change color of status LED on app to closing
            app.Lamp.Color = [0 1 0];
            app.Status_label.Text = 'Closing';
            % Close output files if nooutput unchecked
            % if no output uncheked
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task stops at %s after %d seconds\n', datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask));
                if ~strfind(app.TaskDropDown.Value, 'PBOnly')
                    fprintf(Box.Task.Output.Fid_out_param, 'Min trigger detection loop = %.4f sec\nMax trigger detection loop = %.4f sec\n', Box.Task.LoopMin, Box.Task.LoopMax);
                end
                fprintf(Box.Task.Output.Fid_out_param, 'Total number of sequences or wav files of %d min: %d\n', app.FiledurationminSpinner.Value, Box.Task.Output.Sequence);
                fclose(Box.Task.Output.Fid_out_param);
                fclose(Box.Task.Output.Fid_out_events);
                
                % Give an output for this task performance (nb of sucessful/unsuccessful trials)
                
            end
            % Set filename in soundmexpro to default value (close the previously written file)
            soundmexpro('recfilename', 'filename', Box.MicFile, 'channel', {'Mic'});
            
            if app.TransceiverSyncCheckBox.Value
                soundmexpro('recfilename', 'filename', Box.TTLFile, 'channel', 'TTL_in');
            end
            
%             % Reset counters
%             app.currentunsuccessfulrewardretrievalEditField.Value = 0;
%             app.CurrentrewardsdeliveredEditField.Value = 0;
            
            % Stop and delete from memory the timer that automatically stop the task after a fix duration if it was requested
            if app.AutomaticoffafterCheckBox.Value
                stop(app.Timer_AutoOff);
                delete(app.Timer_AutoOff);
            end
            
            % Change color of status LED on app to off and light on Time out LED in the box
            app.Lamp.Color = [0 0 0];
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1); % Switch on time out LED
            writeDigitalPin(Box.Arduino, Box.LEDCue, 0); % Make sure LED cue is off
            app.TaskSwitch.Value = 'Stop';
            app.Status_label.Text = 'Status';
            
            % Enable all inputs
            enable_manual_control_buttons(app);
            enable_metadata_inputs(app);
            enable_session_parameters_inputs(app);
            enable_sound_detection_inputs(app);
            enable_playback_parameters_inputs(app);
            enable_reward_parameters_input(app);
            enable_punishment_parameters_input(app);
        end
        
        
        function rec_only_task(app)
            global Box
            % No reward, just passive recording saved to file, with sound detection active and saving tigger times to file
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1); % make Time out LED is on
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if a sound is detected
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            while strcmp(app.TaskSwitch.Value, 'Start')
                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                if Success && ~app.NooutputCheckBox.Value
                    % if a sound is detected and nooutput unchecked save the time and sample stamp to file
                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), Stamp,NaN,NaN,NaN);
                    % also save the sound snippet on which sound was detected
                    audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                end
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
            end
        end
        
        function play_back_task(app)
            global Box
            % No reward, just passive recording saved to file, with saving playback of stimuli in folder times to file
            % Determine the number of files to play back
            Nfiles = length(app.PlayListBox.Value);
            
            % update the gain for play back in case compensaton request was changed
            % Set the gain for playing files
            GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain*'));
            if isempty(GainFile)
                fprintf(1,'Warning!!! No Gain file for this set-up the default value (1) will be used for playing sound files with soundmexpro\n')
                Box.PlayBackGain = 1;
            elseif app.CompensationCheckBox.Value
                GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain_Compensated.mat'));
                if isempty(GainFile)
                    fprintf(1,'Warning!!! No Gain file compensated for this set up, the non compensated one is used\n');
                    GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain.mat'));
                end
                Gain = load(fullfile(GainFile.folder, GainFile.name));
                Box.PlayBackGain = Gain.Gain_in;
            else
                GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain.mat'));
                if isempty(GainFile)
                    fprintf(1,'Warning!!! No Gain file NOT compensated for this set up, the compensated one is used\n');
                    GainFile = dir(fullfile(Box.RecPath, 'SoundMexPro_Gain_Compensated.mat'));
                end
                Gain = load(fullfile(GainFile.folder, GainFile.name));
                Box.PlayBackGain = Gain.Gain_in;
            end
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0); % switch off Time out LED
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % for the number of repetitions requested
            for rr=1:app.RepetitionEditField.Value
                % indicate on the app interface the repetition number
                app.CurrentRepEditField.Value = rr;
                % get ready a list for random presentations
                PresInd = randperm(Nfiles,Nfiles);
                for ff=1:Nfiles
                    % get the filename from the app
                    Filename = app.PlayListBox.Value{PresInd(ff)};
                    % indicate the filename on the app
                    app.PlayingEditField.Value = Filename;
                    % Check the sample frequency of the file, it should be the same as the Motu
                    [Y, fs] = audioread(Filename);
                    if fs~=Box.SoundCard.fs
                        error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                    else
                        if ~app.NooutputCheckBox.Value
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_pbevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), Filename,NaN,NaN,NaN);
                        end
                        soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',1, 'gain', Box.PlayBackGain);
                        pause(length(Y)/fs)
                    end
                    % wait for ICI
                    pause(app.MinIntercallintervalsEditField.Value + (app.MaxIntercallintervalsEditField.Value-app.MinIntercallintervalsEditField.Value).*rand(1,1));
                end
            end
        end
        
        function voc_trigger_task(app)
            global Box
            fprintf('running voc trigger task\n')
            if app.RewardPlayBackCheckBox.Value
                % Pulling filename of call to play before reward
                Filename = app.PlayListBox.Value{1};
                % Check the sample frequency of the file, it should be the same as the Motu
                [Y, fs] = audioread(Filename);
                PauseCall = length(Y)/fs;
                if fs~=Box.SoundCard.fs
                    error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                end
            end
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0); % Switch off time out LED
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if a sound is detected and if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            
            while strcmp(app.TaskSwitch.Value, 'Start')
                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                
                % Enter the food delivery mode if a vocalization is detected
                if Success
                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
                    TriggerTimeStamp = toc(Box.Task.TimerTask);
                    writeDigitalPin(Box.Arduino, Box.LEDCue, 1);% set the Cue LED on
                    
                    % Check the Food ports for the duration set in the app and give food if triggered
                    RewardTimer = tic;
                    while toc(RewardTimer) < app.TimedelaytoretrieverewardsEditField.Value
                        if ~strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached') && toc(RewardTimer)>=str2double(app.LEDCueonDropDown.Value(end-1))
                            writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                        end
                        FoodPort = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)];
                        [Success_new, Stamp_new, Wave_new] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                        
                        if sum(FoodPort) %any food port broken?
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, TriggerDateTime,TriggerTimeStamp, Stamp,FoodPort,toc(RewardTimer));
                                % save the sound snippet on which sound was detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                            end
                            
                            % deliver food
                            fprintf(1,'Delivering reward to bats\n');
                            if app.RewardPlayBackCheckBox.Value % playback the rewarding call
                                soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',1); % play the reward call signal
                                pause(PauseCall);
                            end
                            deliver_reward(app)
                            %break the loop
                            break
                        elseif Success_new && ~Success && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Flexible') % A new sound is detected after a silence within the time window and reward timer push requested
                            fprintf(1, 'new vocalization detected, updating the reward timer\n')
                            % Keep track of that new event time:
                            TriggerDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                            TriggerTimeStamp_new = toc(Box.Task.TimerTask);
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp of the previous sound to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, TriggerDateTime,TriggerTimeStamp, Stamp,FoodPort,Inf);
                                % save the sound snippet on which sound was previously detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                            end
                            % Update reward timer
                            RewardTimer = tic;
                            
                            % Update Success, Stamp and Wave Values
                            Wave = Wave_new;
                            Stamp = Stamp_new;
                            TriggerDateTime = TriggerDateTime_new;
                            TriggerTimeStamp = TriggerTimeStamp_new;
                            
                        elseif Success_new && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Strict') % A new sound is detected and reward timer push not requested
                            fprintf(1, 'Vocalization detected during reward time\n')
                            % Keep track of that new event time:
                            TriggerDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                            TriggerTimeStamp_new = toc(Box.Task.TimerTask);
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp of this new sound to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, TriggerDateTime_new,TriggerTimeStamp_new, Stamp_new,FoodPort,NaN);
                                % save the sound snippet on which this new sound was detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp_new)), Wave_new, Box.SoundCard.fs);
                            end
                        end
                        NewCheck = toc(Box.Task.TimerTask);
                        Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                        Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                        LastCheck = NewCheck;
                        Success = Success_new;
                        pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                    end
                    if strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached')
                        writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off, in the other conditions, Timer_CueLED will switch it off
                    end
                    
                    if ~sum(FoodPort) %Bats did not come to retrieve reward
                        % if no output uncheked
                        if ~app.NooutputCheckBox.Value
                            % save the time and sample stamp of the non-rewarded sound to file
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent,TriggerDateTime, TriggerTimeStamp, Stamp,FoodPort, Inf);
                            % save the non-rewarded sound snippet on which sound was detected
                            audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                        end
                        
                        % Give bats a time out or dunce cap for ignoring the cue, only record sounds
                        if app.TimeoutforignoringthecueCheckBox.Value || app.DuncecapforignoringthecueCheckBox.Value
                            if ~app.DuncecapforignoringthecueCheckBox.Value && app.TimeoutforignoringthecueCheckBox.Value % No dunce cap requested but TO requested
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now :%s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                                % Dunce cap requested and number of unsuccessful trials reached
                            elseif app.DuncecapforignoringthecueCheckBox.Value && (app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value == (app.currentunsuccessfulrewardretrievalEditField.Value + 1))
                                TO_duration = app.DunceCapDuration.Value;
                                fprintf(1, 'Bats Dunce Cap for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                app.currentunsuccessfulrewardretrievalEditField.Value = 0;% reset the failed trial counter
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the Timeout LED on
                            elseif app.DuncecapforignoringthecueCheckBox.Value && ~app.TimeoutforignoringthecueCheckBox.Value% Dunce cap requested but threshold for dunce cap not reached and TO not requested
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = 0;
                            else % TO and Dunce cap requested but threshold for dunce cap not reached
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                            end
                            
                            TimeOut = tic;
                            while toc(TimeOut)<TO_duration
                                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                                if Success && ~app.NooutputCheckBox.Value
                                    % save the time and sample stamp to file
                                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), Stamp,NaN,NaN,NaN);
                                    % save the sound snippet on which sound was detected
                                    audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                                end
                                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                            end
                            if TO_duration
                                fprintf(1, 'Time out over, Back to normal Now: %s!!\n',  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0);% set the TimeOut LED off
                            end
                        end
                    end
                    pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                end
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
            end
        end
        
        function fp_trigger_task(app)
            global Box
            fprintf('running food port task\n')
            if app.RewardPlayBackCheckBox.Value
                % Pulling filename of call to play before reward
                Filename = app.PlayListBox.Value{1};
                % Check the sample frequency of the file, it should be the same as the Motu
                [Y, fs] = audioread(Filename);
                PauseCall = length(Y)/fs;
                if fs~=Box.SoundCard.fs
                    error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                end
            end
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0); % Switch off time out LED
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            FoodPort_old = [0 0];
            
            while strcmp(app.TaskSwitch.Value, 'Start')
                FoodPort = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)];
                if sum(FoodPort) && ~sum(FoodPort_old) %any food port broken after a break in food port activation?
                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
                    TriggerTimeStamp = toc(Box.Task.TimerTask);
                    writeDigitalPin(Box.Arduino, Box.LEDCue, 1);% set the Cue LED on
                    
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        % save the time and sample stamp to file
                        fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_fpevent, TriggerDateTime,TriggerTimeStamp, NaN,FoodPort,NaN);
                    end
                    % deliver food
                    fprintf(1,'Delivering reward to bats\n');
                    if app.RewardPlayBackCheckBox.Value % playback the rewarding call
                        soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',1); % play the reward call signal
                        pause(PauseCall);
                    end
                    deliver_reward(app)
                    writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                end
                FoodPort_old = FoodPort; % update food port status
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
            end
        end
        
        function bb_trigger_task(app)
            global Box
            if app.RewardPlayBackCheckBox.Value
                % Pulling filename of call to play before reward
                Filename = app.PlayListBox.Value{1};
                % Check the sample frequency of the file, it should be the same as the Motu
                [Y, fs] = audioread(Filename);
                PauseCall = length(Y)/fs;
                if fs~=Box.SoundCard.fs
                    error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                end
            end
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0); % Switch off time out LED
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if a bat is detected at the input beam and if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            InputBeam_old = [0 0]; % This variable is used to update immediate pas status of the input beam and make sure bats do not break the input beam at all time
            % Note that FoodPort_old is used to update immediate pas status of the food port and make sure bats do not break the food port beam at all time
            
            while strcmp(app.TaskSwitch.Value, 'Start')
                InputBeam = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                FoodPort_old = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)]; % we expect the bat not to be at the food port when it is breaking the input beam
                
                % Enter the food delivery mode if a bat is detected at an input beam and there was no detection at the previous check and the bat is not at the food port at the same time
                if sum(InputBeam) && ~sum(InputBeam_old) && ~sum(FoodPort_old)
                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
                    TriggerTimeStamp = toc(Box.Task.TimerTask);
                    writeDigitalPin(Box.Arduino, Box.LEDCue, 1);% set the Cue LED on
                    InputBeam_old = InputBeam;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                    
                    % Check the Food ports for the duration set in the app and give food if triggered
                    RewardTimer = tic;
                    while toc(RewardTimer) < app.TimedelaytoretrieverewardsEditField.Value
                        if ~strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached') && toc(RewardTimer)>=str2double(app.LEDCueonDropDown.Value(end-1))
                            writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                        end
                        FoodPort = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)];
                        InputBeam_new = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                        
                        if sum(FoodPort) && ~sum(InputBeam_new) %any food port broken while away from the input beam?
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, TriggerDateTime,TriggerTimeStamp, NaN,InputBeam_old,FoodPort,toc(RewardTimer));
                            end
                            % deliver food
                            fprintf(1,'Delivering reward to bats\n');
                            if app.RewardPlayBackCheckBox.Value % playback the rewarding call
                                soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',1); % play the reward call signal
                                pause(PauseCall);
                            end
                            deliver_reward(app)
                            %break the loop
                            InputBeam = InputBeam_new;
                            break
                        elseif sum(InputBeam_new) && ~sum(InputBeam) && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Flexible') % A new trigger is detected after a silence within the time window and reward timer update is requested
                            fprintf(1, 'New input beam break detected, updating the reward timer\n')
                            % Keep track of that new event time:
                            TriggerDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                            TriggerTimeStamp_new = toc(Box.Task.TimerTask);
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp of the previous event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, TriggerDateTime,TriggerTimeStamp, NaN,InputBeam_old,FoodPort,Inf);
                            end
                            InputBeam_old = InputBeam_new;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                            % Update reward timer
                            RewardTimer = tic;
                            
                            % Update Stamps and Wave Values
                            TriggerDateTime = TriggerDateTime_new;
                            TriggerTimeStamp = TriggerTimeStamp_new;
                            
                        elseif sum(InputBeam_new) && ~sum(InputBeam) && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Strict') % A new trigger is detected and reward timer update is not requested
                            fprintf(1, 'Input beam break detected during reward time\n')
                            % Keep track of that new event time:
                            TriggerDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                            TriggerTimeStamp_new = toc(Box.Task.TimerTask);
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value
                                % save the time and sample stamp of this new event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, TriggerDateTime_new,TriggerTimeStamp_new, NaN,InputBeam_new,FoodPort,NaN);
                            end
                        end
                        NewCheck = toc(Box.Task.TimerTask);
                        Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                        Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                        LastCheck = NewCheck;
                        InputBeam = InputBeam_new;
                        pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                    end
                    
                    if strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached')
                        writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off, in the other conditions, Timer_CueLED will switch it off
                    end
                    
                    if ~sum(FoodPort) || (sum(FoodPort) && sum(InputBeam)) %Bats did not come to retrieve reward during the reward time window or came but were not leaving the input beam
                        % if no output uncheked
                        if ~app.NooutputCheckBox.Value
                            % save the time and sample stamp of the non-rewarded event to file
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent,TriggerDateTime, TriggerTimeStamp, NaN,InputBeam_old,FoodPort, Inf);
                        end
                        
                        % Give bats a time out or dunce cap for ignoring the cue, but keep recording any attempt to break input beams
                        if app.TimeoutforignoringthecueCheckBox.Value || app.DuncecapforignoringthecueCheckBox.Value
                            InputBeam_old = InputBeam;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                            if ~app.DuncecapforignoringthecueCheckBox.Value && app.TimeoutforignoringthecueCheckBox.Value % No dunce cap requested but TO requested
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now :%s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                                % Dunce cap requested and number of unsuccessful trials reached
                            elseif app.DuncecapforignoringthecueCheckBox.Value && (app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value == (app.currentunsuccessfulrewardretrievalEditField.Value + 1))
                                TO_duration = app.DunceCapDuration.Value;
                                fprintf(1, 'Bats Dunce Cap for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                app.currentunsuccessfulrewardretrievalEditField.Value = 0;% reset the failed trial counter
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the Timeout LED on
                            elseif app.DuncecapforignoringthecueCheckBox.Value && ~app.TimeoutforignoringthecueCheckBox.Value% Dunce cap requested but threshold for dunce cap not reached and TO not requested
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = 0;
                            else % TO and Dunce cap requested but threshold for dunce cap not reached
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                            end
                            
                            TimeOut = tic;
                            while toc(TimeOut)<TO_duration
                                InputBeam = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                                FoodPort_old = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)]; % we expect the bat not to be at the food port when it is breaking the input beam
                                if sum(InputBeam) && ~sum(InputBeam_old) && ~sum(FoodPort_old) && ~app.NooutputCheckBox.Value
                                    % save the time and sample stamp to file
                                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), NaN,InputBeam,NaN,NaN);
                                    fprintf(1, 'Input beam break detected during time out\n')
                                end
                                InputBeam_old=InputBeam; % update the value of Success_old wich keeps track of past status in the outer loop (outside feeding loop)
                                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                            end
                            if TO_duration
                                fprintf(1, 'Time out over, Back to normal Now: %s!!\n',  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0);% set the TimeOut LED off
                            end
                        end
                    end
                    pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                end
                InputBeam_old = InputBeam;% update the value of Success_old wich keeps track of past status in the outer loop (outside feeding loop)
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
            end
        end
        

        function bbvoc_trigger_task(app)
            global Box
            fprintf('We are running BB Voc\n')
            
            % Pulling filename of call to play before reward
            Filename = app.PlayListBox.Value{1};
            % Check the sample frequency of the file, it should be the same as the Motu
            [Y, fs] = audioread(Filename);
            PauseCall = length(Y)/fs;
            if fs~=Box.SoundCard.fs
                error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
            end
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0); % Switch off time out LED
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if a bat is detected at the input beam, then if it is vocalizing and then if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            InputBeam_old = [0 0]; % This variable is used to update immediate pas status of the input beam and make sure bats do not break the input beam at all time
            % Note that FoodPort_old is used to update immediate pas status of the food port and make sure bats do not break the food port beam at all time
            
            while strcmp(app.TaskSwitch.Value, 'Start')
                InputBeam = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                FoodPort_old = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)]; % we expect the bat not to be at the food port when it is breaking the input beam
                
                % Enter the sound detection mode if the Input beam is broken and there was no detection at the previous check and the bat is not at the food port at the same time
                if sum(InputBeam) && ~sum(InputBeam_old) && ~sum(FoodPort_old)
                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
                    TriggerTimeStamp = toc(Box.Task.TimerTask);
                    fprintf('CueLED on\n');
                    writeDigitalPin(Box.Arduino, Box.LEDCue, 1);% set the Cue LED on
                    InputBeam_old = InputBeam;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                    
                    % Check if any vocalization is produced for the duration set in the app
                    VocProdTimer = tic;
                    NoRe = 1; % This will keep track of the reward status to get out of the intermediate loop once the bat was rewarded
                    while NoRe && (toc(VocProdTimer) < app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Value)
                        [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                        % Enter the food delivery mode if a vocalization is detected
                        if Success
                            if strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached')
                                writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                            end
                            VocDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
                            VocTimeStamp = toc(Box.Task.TimerTask);
                            Delay2Voc = toc(VocProdTimer);
                            if app.RewardPlayBackCheckBox.Value % playback the rewarding call
                                soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',1); % play the reward call signal
                                pause(PauseCall);
                            end
                            % Check the Food ports for the duration set in the app and give food if triggered
                            RewardTimer = tic;
                            while toc(RewardTimer) < app.TimedelaytoretrieverewardsEditField.Value
                                if ~strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached') && toc(RewardTimer)>=str2double(app.LEDCueonDropDown.Value(end-1))
                                    writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                                end
                                FoodPort = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)];
                                InputBeam_new = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                                [Success_new, Stamp_new, Wave_new] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                                
                                if sum(FoodPort) && ~sum(InputBeam_new) %any food port broken while away from the input beam?
                                    % if no output uncheked
                                    if ~app.NooutputCheckBox.Value
                                        % save the time and sample stamp to file
                                        fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, TriggerDateTime,TriggerTimeStamp,VocDateTime,VocTimeStamp, Stamp,InputBeam,FoodPort,Delay2Voc,toc(RewardTimer));
                                        % save the sound snippet on which sound was detected
                                        audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                                    end
                                    % deliver food
                                    fprintf(1,'Delivering reward to bats\n');
                                    deliver_reward(app)
                                    % update the input beam value
                                    InputBeam = InputBeam_new;
                                    % Set NoRe to break the sound detection loop
                                    NoRe = 0;
                                    % break the feeding loop
                                    break
                                elseif Success_new && ~Success && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Flexible') % A new trigger is detected after a silence within the time window and reward timer update is requested
                                    fprintf(1, 'New vocalization detected, updating the reward timer\n')
                                    % Keep track of that new event time:
                                    VocDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                                    VocTimeStamp_new = toc(Box.Task.TimerTask);
                                    Delay2Voc_new = toc(VocProdTimer);
                                    % if no output uncheked
                                    if ~app.NooutputCheckBox.Value
                                        % save the time and sample stamp of the previous event to file
                                        fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, TriggerDateTime,TriggerTimeStamp,VocDateTime,VocTimeStamp, Stamp,InputBeam_old,FoodPort,Delay2Voc,Inf);
                                        % save the sound snippet on which sound was detected
                                        audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                                    end
                                    
                                    % Update reward timer
                                    RewardTimer = tic;
                                    
                                    % Update Stamps and Wave Values
                                    VocDateTime = VocDateTime_new;
                                    VocTimeStamp = VocTimeStamp_new;
                                    Delay2Voc = Delay2Voc_new;
                                    Stamp = Stamp_new;
                                    
                                elseif Success_new && ~Success && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Strict') % A new trigger is detected and reward timer update is not requested
                                    fprintf(1, 'New vocalization detected during reward time\n')
                                    % Keep track of that new event time:
                                    VocDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                                    VocTimeStamp_new = toc(Box.Task.TimerTask);
                                    Delay2Voc_new = toc(VocProdTimer);
                                    % if no output uncheked
                                    if ~app.NooutputCheckBox.Value
                                        % save the time and sample stamp of this new event to file
                                        fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, TriggerDateTime,TriggerTimeStamp,VocDateTime_new,VocTimeStamp_new, Stamp_new,InputBeam_old,FoodPort, Delay2Voc_new, NaN);
                                        % save the sound snippet on which sound was detected
                                        audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp_new)), Wave_new, Box.SoundCard.fs);
                                    end
                                end
                                NewCheck = toc(Box.Task.TimerTask);
                                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                                LastCheck = NewCheck;
                                InputBeam = InputBeam_new;
                                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                            end
                            
                            %Delay to retrieve reward is over or bats already retrieved reward
                            if strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached')
                                writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off, in the other conditions, Timer_CueLED will switch it off
                            end
                        end
                        if ~strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached') && toc(VocProdTimer)>=str2double(app.LEDCueonDropDown.Value(end-1))
                             writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                        end
                    end
                    if NoRe %Bats did not come to retrieve reward during the reward time window or came but were not leaving the input beam or did not vocalize
                        writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                        % if no output uncheked
                        if ~app.NooutputCheckBox.Value
                            if Success %Bats did call during the trial but did not get the reward
                                % save the time and sample stamp of the non-rewarded event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, TriggerDateTime,TriggerTimeStamp,VocDateTime,VocTimeStamp, Stamp,InputBeam_old,FoodPort, Delay2Voc,Inf);
                                % save the sound snippet on which sound was detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                            else % Bat did not call
                                % save the time and sample stamp of the non-rewarded event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, TriggerDateTime,TriggerTimeStamp,NaN,NaN, NaN,InputBeam_old,FoodPort,Inf,NaN);
                            end
                        end
                        
                        % Give bats a time out or dunce cap for ignoring the cue, but keep recording any attempt to break input beams
                        if app.TimeoutforignoringthecueCheckBox.Value || app.DuncecapforignoringthecueCheckBox.Value
                            InputBeam_old = InputBeam;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                            if ~app.DuncecapforignoringthecueCheckBox.Value && app.TimeoutforignoringthecueCheckBox.Value % No dunce cap requested but TO requested
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now :%s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                                % Dunce cap requested and number of unsuccessful trials reached
                            elseif app.DuncecapforignoringthecueCheckBox.Value && (app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value == (app.currentunsuccessfulrewardretrievalEditField.Value + 1))
                                TO_duration = app.DunceCapDuration.Value;
                                fprintf(1, 'Bats Dunce Cap for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                app.currentunsuccessfulrewardretrievalEditField.Value = 0;% reset the failed trial counter
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the Timeout LED on
                            elseif app.DuncecapforignoringthecueCheckBox.Value && ~app.TimeoutforignoringthecueCheckBox.Value% Dunce cap requested but threshold for dunce cap not reached and TO not requested
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = 0;
                            else % TO and Dunce cap requested but threshold for dunce cap not reached
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                            end
                            
                            TimeOut = tic;
                            while toc(TimeOut)<TO_duration
                                InputBeam = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                                FoodPort_old = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)]; % we expect the bat not to be at the food port when it is breaking the input beam
                                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                                if Success && ~app.NooutputCheckBox.Value
                                    % save the time and sample stamp to file
                                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, NaN,NaN,datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), Stamp,NaN,NaN, NaN, NaN,NaN,NaN);
                                    % save the sound snippet on which sound was detected
                                    audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                                end
                                if sum(InputBeam) && ~sum(InputBeam_old) && ~sum(FoodPort_old) && ~app.NooutputCheckBox.Value
                                    % save the time and sample stamp to file
                                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbvocevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask),NaN, NaN, NaN,InputBeam,NaN,NaN,NaN);
                                    fprintf(1, 'Input beam break detected during time out\n')
                                end
                                InputBeam_old=InputBeam; % update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                            end
                            if TO_duration
                                fprintf(1, 'Time out over, Back to normal Now: %s!!\n',  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0);% set the TimeOut LED off
                            end
                        end
                    end
                    
                    pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                end
                InputBeam_old = InputBeam;% update the value of Success_old wich keeps track of past status in the outer loop (outside feeding loop)
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
            end
        end
        
        function bborvoc_trigger_task(app)
            global Box
            fprintf('Running BB or Voc Trigger task\n')
            
            if app.RewardPlayBackCheckBox.Value
                % Pulling filename of call to play before reward
                Filename = app.PlayListBox.Value{1};
                % Check the sample frequency of the file, it should be the same as the Motu
                [~, fs] = audioread(Filename);
                if fs~=Box.SoundCard.fs
                    error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
                end
            end
            
            % Start recording from soundmexpro
            app.Lamp.Color = [1 0 0];
            app.Status_label.Text = 'Recording';
            writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0); % Switch off time out LED
            if ~app.NooutputCheckBox.Value
                fprintf(Box.Task.Output.Fid_out_param, 'Task starts at %s\n', datestr(now, 'yyyymmddTHHMMSSFFF'));
            end
            Box.Task.TimerTask = tic;
            if app.TransceiverSyncCheckBox.Value
                if 1~=soundmexpro('recpause', 'value', zeros(1,length([Box.Channels.Mic Box.Channels.TTL_in])), 'channel', 0:(length([Box.Channels.Mic Box.Channels.TTL_in])-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            else
                if 1~=soundmexpro('recpause', 'value', zeros(1,length(Box.Channels.Mic)), 'channel', 0:(length(Box.Channels.Mic)-1))
                    error('Soundmexpro cannot resume recording using recpause\n');
                end
            end
            soundmexpro('start', 'length', 0); %start recording endlessly
            
            % check as often as possible if a bat is detected at the input beam, if it's vocalizing and if bats are at the Food port
            % initialize variable to check the loop speed
            Box.Task.LoopMax = -Inf;
            Box.Task.LoopMin = Inf;
            LastCheck = toc(Box.Task.TimerTask);
            InputBeam_old = [0 0]; % This variable is used to update immediate pas status of the input beam and make sure bats do not break the input beam at all time
            Success_old = 0;
            % Note that FoodPort_old is used to update immediate pas status of the food port and make sure bats do not break the food port beam at all time
            
            while strcmp(app.TaskSwitch.Value, 'Start')
                InputBeam = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                FoodPort_old = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)]; % we expect the bat not to be at the food port when it is breaking the input beam
                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                
                % Enter the food delivery mode if a bat is detected at an input beam and there was no detection at the previous check, or it's vocalizing and the bat is not at the food port at the same time
                if ((sum(InputBeam) && ~sum(InputBeam_old)) || (Success && ~Success_old)) && ~sum(FoodPort_old)
                    TriggerDateTime = datestr(now, 'yyyymmddTHHMMSSFFF');
                    TriggerTimeStamp = toc(Box.Task.TimerTask);
                    writeDigitalPin(Box.Arduino, Box.LEDCue, 1);% set the Cue LED on
                    InputBeam_old = InputBeam;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                    Success_old = Success;
                    
                    % Check the Food ports for the duration set in the app and give food if triggered
                    RewardTimer = tic;
                    while toc(RewardTimer) < app.TimedelaytoretrieverewardsEditField.Value
                        if ~strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached') && toc(RewardTimer)>=str2double(app.LEDCueonDropDown.Value(end-1))
                            writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off
                        end
                        FoodPort = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)];
                        InputBeam_new = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                        [Success_new, Stamp_new, Wave_new] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                        
                        if sum(FoodPort) && ~sum(InputBeam_new) && ~Success_new %any food port broken while away from the input beam?
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value && sum(InputBeam_old)
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, TriggerDateTime,TriggerTimeStamp, NaN,InputBeam_old,FoodPort,toc(RewardTimer));
                            elseif ~app.NooutputCheckBox.Value && Success_old
                                % save the time and sample stamp to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, TriggerDateTime,TriggerTimeStamp, Stamp,InputBeam_old,FoodPort,toc(RewardTimer));
                                % save sound that ellicited reward
                                % save the sound snippet on which sound was detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                            end
                            % Play reward call
                            if app.RewardPlayBackCheckBox.Value % playback the rewarding call
                                soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',1); % play the reward call signal
                            end
                            % deliver food
                            fprintf(1,'Delivering reward to bats\n');
                            deliver_reward(app)
                            %break the loop
                            InputBeam = InputBeam_new;
                            Success = Success_new;
                            break
                        elseif ((Success_new && ~Success) || (sum(InputBeam_new) && ~sum(InputBeam))) && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Flexible') % A new trigger is detected after a silence within the time window and reward timer update is requested
                            fprintf(1, 'New input beam break or vocalization detected, updating the reward timer\n')
                            % Keep track of that new event time:
                            TriggerDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                            TriggerTimeStamp_new = toc(Box.Task.TimerTask);
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value && sum(InputBeam_new)
                                % save the time and sample stamp of the previous event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, TriggerDateTime,TriggerTimeStamp, NaN,InputBeam_old,FoodPort,Inf);
                            elseif ~app.NooutputCheckBox.Value && Success_new
                                % save the time and sample stamp of the previous event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, TriggerDateTime,TriggerTimeStamp, Stamp,InputBeam_old,FoodPort,Inf);
                                % save the sound snippet on which sound was detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                                % Update Stamp and Wave Values
                                Wave = Wave_new;
                                Stamp = Stamp_new;
                            end
                            InputBeam_old = InputBeam_new;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                            % Update reward timer
                            RewardTimer = tic;
                            
                            % Update Stamps and Wave Values
                            TriggerDateTime = TriggerDateTime_new;
                            TriggerTimeStamp = TriggerTimeStamp_new;
                            
                        elseif ((Success_new && ~Success) || (sum(InputBeam_new) && ~sum(InputBeam))) && strcmp(app.Delay2retrieverewardStrictFlexDropDown.Value, 'Strict') % A new trigger is detected and reward timer update is not requested
                            fprintf(1, 'Input beam break or vocalization detected during reward time\n')
                            % Keep track of that new event time:
                            TriggerDateTime_new = datestr(now, 'yyyymmddTHHMMSSFFF');
                            TriggerTimeStamp_new = toc(Box.Task.TimerTask);
                            % if no output uncheked
                            if ~app.NooutputCheckBox.Value && sum(InputBeam_new)
                                % save the time and sample stamp of this new event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, TriggerDateTime_new,TriggerTimeStamp_new, NaN,InputBeam_new,FoodPort,NaN);
                            elseif ~app.NooutputCheckBox.Value && Success_new
                                % save the time and sample stamp of this new event to file
                                fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, TriggerDateTime_new,TriggerTimeStamp_new, Stamp_new,InputBeam_new,FoodPort,NaN);
                                % save the sound snippet on which sound was detected
                                audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp_new)), Wave_new, Box.SoundCard.fs);
                            end
                        end
                        NewCheck = toc(Box.Task.TimerTask);
                        Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                        Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                        LastCheck = NewCheck;
                        InputBeam = InputBeam_new;
                        Success = Success_new;
                        pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                    end
                    
                    if strcmp(app.LEDCueonDropDown.Value, 'Until reward or delay reached')
                        writeDigitalPin(Box.Arduino, Box.LEDCue, 0);% set the Cue LED off, in the other conditions, Timer_CueLED will switch it off
                    end
                    
                    if ~sum(FoodPort) || (sum(FoodPort) && sum(InputBeam)) %Bats did not come to retrieve reward during the reward time window or came but were not leaving the input beam
                        % if no output uncheked
                        if ~app.NooutputCheckBox.Value
                            % save the time and sample stamp of the non-rewarded event to file
                            fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent,TriggerDateTime, TriggerTimeStamp, NaN,InputBeam_old,FoodPort, Inf);
                        end
                        
                        % Give bats a time out or dunce cap for ignoring the cue, but keep recording any attempt to break input beams
                        if app.TimeoutforignoringthecueCheckBox.Value || app.DuncecapforignoringthecueCheckBox.Value
                            InputBeam_old = InputBeam;% update the value of InputBeam_old wich keeps track of past status in the outer loop (outside feeding loop)
                            if ~app.DuncecapforignoringthecueCheckBox.Value && app.TimeoutforignoringthecueCheckBox.Value % No dunce cap requested but TO requested
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now :%s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                                % Dunce cap requested and number of unsuccessful trials reached
                            elseif app.DuncecapforignoringthecueCheckBox.Value && (app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value == (app.currentunsuccessfulrewardretrievalEditField.Value + 1))
                                TO_duration = app.DunceCapDuration.Value;
                                fprintf(1, 'Bats Dunce Cap for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                app.currentunsuccessfulrewardretrievalEditField.Value = 0;% reset the failed trial counter
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the Timeout LED on
                            elseif app.DuncecapforignoringthecueCheckBox.Value && ~app.TimeoutforignoringthecueCheckBox.Value% Dunce cap requested but threshold for dunce cap not reached and TO not requested
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = 0;
                            else % TO and Dunce cap requested but threshold for dunce cap not reached
                                app.currentunsuccessfulrewardretrievalEditField.Value = app.currentunsuccessfulrewardretrievalEditField.Value +1 ;
                                TO_duration = app.TimeOutDuration.Value;
                                fprintf(1, 'Bats time out for %f Now: %s\n', TO_duration,  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);% set the TimeOut LED on
                            end
                            
                            TimeOut = tic;
                            while toc(TimeOut)<TO_duration
                                InputBeam = [~readDigitalPin(Box.Arduino, Box.BeamInputFront) ~readDigitalPin(Box.Arduino, Box.BeamInputBack)];
                                FoodPort_old = [~readDigitalPin(Box.Arduino, Box.BeamFoodFront) ~readDigitalPin(Box.Arduino, Box.BeamFoodBack)]; % we expect the bat not to be at the food port when it is breaking the input beam
                                [Success, Stamp, Wave] = soundDetect(app, Box, Box.Task.NumBufClip, Box.Task.Filter, Box.Task.RmsThresh);
                                if sum(InputBeam) && ~sum(InputBeam_old) && ~sum(FoodPort_old) && ~app.NooutputCheckBox.Value
                                    % save the time and sample stamp to file
                                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_bbevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), NaN,InputBeam,NaN,NaN);
                                    fprintf(1, 'Input beam break detected during time out\n')
                                elseif Success && ~app.NooutputCheckBox.Value
                                    % save the time and sample stamp to file
                                    fprintf(Box.Task.Output.Fid_out_events, Box.Task.Output.FormatSpec_vocevent, datestr(now, 'yyyymmddTHHMMSSFFF'), toc(Box.Task.TimerTask), Stamp,InputBeam,NaN,NaN);
                                    % save the sound snippet on which sound was detected
                                    audiowrite(fullfile(Box.Task.Output.SnipDir, sprintf([Box.Task.Output.SnipFile '_%s.wav'],Stamp)), Wave, Box.SoundCard.fs);
                                    fprintf(1, 'Vocalization detected during time out\n')
                                end
                                
                                InputBeam_old=InputBeam; % update the value of Success_old wich keeps track of past status in the outer loop (outside feeding loop)
                                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                            end
                            if TO_duration
                                fprintf(1, 'Time out over, Back to normal Now: %s!!\n',  datestr(now, 'yyyymmddTHHMMSSFFF'))
                                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0);% set the TimeOut LED off
                            end
                        end
                    end
                    pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
                end
                InputBeam_old = InputBeam;% update the value of Success_old wich keeps track of past status in the outer loop (outside feeding loop)
                Success_old = Success;
                NewCheck = toc(Box.Task.TimerTask);
                Box.Task.LoopMax = max(Box.Task.LoopMax, NewCheck-LastCheck);
                Box.Task.LoopMin = min(Box.Task.LoopMin, NewCheck-LastCheck);
                LastCheck = NewCheck;
                pause(0.000001) % this pause forces the code to check the status of buttons and any pending callback function
            end
        end
        
        function deliver_reward(app)
            global Box
            if app.MaxrewardsEditField.Value > app.CurrentrewardsdeliveredEditField.Value
                Box.dcm.Speed = app.MotorspeedSpinner.Value; % Defining the speed of the syringe motor forward
                start(Box.dcm); % initiate the forward movement of the motor
                pause(app.RewarddurationsEditField.Value); %amount of time to push motor forward
                stop(Box.dcm); % stop the forward movement of the motor
                app.CurrentrewardsdeliveredEditField.Value =  app.CurrentrewardsdeliveredEditField.Value +1; % Update the number of reward delivered
            else
                fprintf(1,'Maximum number of reward reached, the task is stopped\n')
                % stop the task
                stop_task(app)
                
                % reset the motor
                reset_button_pushed(app)
                
                % start a reconly task
                % CHange the task type
                app.TaskDropDown.Value = 'RecOnly';
                % Set the timer of the task to 10hrs
                app.DurationhoursEditField.Value = 10;
                % hit the start button
                app.TaskSwitch.Value = 1;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BoxNum)
            % First clear the Box variable in case it's been created in previous runs of the app and not correctly deleted
            clearvars -global Box
            global Box
            % Set the box ID requested in input or take values given in the app
            if nargin<2
                BoxNum=input('Indicate the box number:\n');
            end
            
            % set the box number to the correct value
            app.BoxIDLabel.Text=sprintf('Box %d',BoxNum);
            
            % Initiate a structure for the box
            Box.ID = BoxNum;
            
            %% initialize arduino
            [Box]=init_arduino(app, Box);
            
            %% initialize SoundMex
            [Box] = init_soundmex(app, Box);
            
        end

        % Button pushed function: FwdButton
        function fwd_button_pushed(app, event)
            global Box
            Box.dcm.Speed = 1; % Defining the speed of the syringe motor forward
            start(Box.dcm); % initiate the forward movement of the motor
            pause(1); %amount of time to push motor forward
            stop(Box.dcm); % stop the forward movement of the motor
            
        end

        % Button pushed function: BwdButton
        function bwd_button_pushed(app, event)
            global Box
            Box.dcm.Speed = -1; % Defining the speed of the syringe motor backward
            start(Box.dcm); % initiate the backward movement of the motor
            pause(1); %amount of time to push motor backward
            stop(Box.dcm); % stop the backward movement of the motor
        end

        % Button pushed function: ResetButton
        function reset_button_pushed(app, event)
            global Box
            Box.dcm.Speed = -1; % Defining the speed of the syringe motor backward
            start(Box.dcm); % initiate the backward movement of the motor
            pause(29); %amount of time to push motor backward in initial position
            stop(Box.dcm); % stop the backward movement of the motor
        end

        % Button pushed function: TestCueButton
        function cue_LED_flash(app, event)
            global Box
            for tt=1:5
                writeDigitalPin(Box.Arduino, Box.LEDCue, 1);
                pause(0.5)
                writeDigitalPin(Box.Arduino, Box.LEDCue, 0);
                pause(0.5)
            end
        end

        % Button pushed function: TestTimeoutButton
        function time_out_LED_flash(app, event)
            global Box
            for tt=1:5
                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 0);
                pause(0.5)
                writeDigitalPin(Box.Arduino, Box.LEDTimeOut, 1);
                pause(0.5)
            end
        end

        % Button pushed function: PlayButton
        function play_button_pushed(app, event)
            global Box
            Filename = app.PlayListBox.Value{1};
            % Check the sample frequency of the file, it should be the same as the Motu
            [~, fs] = audioread(Filename);
            if fs~=Box.SoundCard.fs
                error('the sample frequency of the sound selected in the dropbox under Playback tab settings is %d which is different from the sample rate of the sound card: %d\n',fs,Box.SoundCard.fs);
            else
                soundmexpro('loadfile', 'filename', Filename, 'track', 'TSpeaker', 'loopcount',3);
                soundmexpro('start', 'length', -1); % length=-1 ensure soundmex is off after sound is played
            end
        end

        % Button pushed function: UpdatefilelistButton
        function update_file_list_pushed(app, event)
            global Box
            %% List the files in the playback folder
            Files = dir(fullfile(Box.PlayPath, '*.wav'));
            app.PlayListBox.ItemsData = cell(length(Files),1);
            for ff=1:length(Files)
                app.PlayListBox.Items{ff} = Files(ff).name;
                app.PlayListBox.ItemsData{ff} = fullfile(Box.PlayPath, Files(ff).name);
            end
        end

        % Value changed function: Detect
        function detect_sound_mic(app, event)
            global Box
            % Freeze input parameters
            disable_sound_detection_inputs(app);
            
            % Get parameters from app and set the threshold amplitude and the duration of the sample that should reach...
            % that threshold for sound to be detected on input recordings
            [NumBufClip,Filter,RmsThresh] = set_sound_detection_parameters(app, Box);
            
            % run a function for RecDur sec that detects sound and lights up the LED Cue when it does
            RecDur = 10;
            soundmexpro('start', 'length', (RecDur+1)*Box.SoundCard.fs); % enable soumdmexpro recording and playback for RecDur +1 seconds
            app.MicLamp.Color = [1 0 0]; % Set the lamp app color to Red
            Timer=tic;
            while toc(Timer)<RecDur
                [success, ~] = soundDetect(app,Box,NumBufClip, Filter, RmsThresh);
                writeDigitalPin(Box.Arduino, Box.LEDCue, success);
            end
            clear Timer
            writeDigitalPin(Box.Arduino, Box.LEDCue, 0);
            app.MicLamp.Color = [0 0 0]; % Set back the lamp app color to Black
            enable_sound_detection_inputs(app);
            app.Detect.Value = 0;
        end

        % Value changed function: TaskSwitch
        function task_switch_action(app, event)
            value = app.TaskSwitch.Value;
            global Box
            if strcmp(value, 'Start')
                app.Lamp.Color = [0 1 0];
                app.Status_label.Text = 'Loading';
                %% Set values of parameters common to all protocols
                % Disable all inputs
                disable_manual_control_buttons(app);
                disable_metadata_inputs(app);
                disable_session_parameters_inputs(app);
                disable_sound_detection_inputs(app);
                disable_playback_parameters_inputs(app);
                disable_reward_parameters_input(app);
                disable_punishment_parameters_input(app);
                
                % Timeline of the experiment (limit on experiment duration, any break
                % requested...)
                if app.AutomaticoffafterCheckBox.Value
                    % create a timer to stop the experiment after the requested time
                    app.Timer_AutoOff = timer;
                    app.Timer_AutoOff.Timerfcn = {@app.stop_task};
                    app.Timer_AutoOff.ExecutionMode = 'singleShot';
                    app.Timer_AutoOff.BusyMode = 'queue';
                    app.Timer_AutoOff.StartDelay = app.DurationhoursEditField.Value*3600; % duration of the task transform from hours to seconds
                    start(app.Timer_AutoOff);
                end
                
                if app.EnforcesleephoursCheckBox.Value
                    % create two timers, one to stop the experiment at the indicated time
                    % and another timer to wake-up the bats the following morning
                    error('Enforcing sleep hours feature is not available yet :-(\n')
                end
                
                
                %% Sound detection parameters (detection thresholds and possible
                % acoustic properties requirements if requested)
                % Get parameters from app and set the threshold amplitude and the duration of the sample that should reach...
                % that threshold for sound to be detected on input recordings
                [Box.Task.NumBufClip,Box.Task.Filter,Box.Task.RmsThresh] = set_sound_detection_parameters(app, Box);
                
                %% Get the task that was selected and start the corresponding function
                if strcmp(app.TaskDropDown.Value, 'RecOnly')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'RecOnly')
                    end
                    rec_only_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'VocTrigger')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'VocTrigger')
                    end
                    voc_trigger_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'FPTrigger')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'FPTrigger')
                    end
                    fp_trigger_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'BBTrigger')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'BBTrigger')
                    end
                    bb_trigger_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'BBVocTrigger')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'BBVocTrigger')
                    end
                    bbvoc_trigger_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'BBorVocTrigger')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'BBorVocTrigger')
                    end
                    bborvoc_trigger_task(app)
                elseif strcmp(app.TaskDropDown.Value, 'PBOnly')
                    % if no output uncheked
                    if ~app.NooutputCheckBox.Value
                        init_task(app, 'PBOnly')
                    end
                    play_back_task(app)
                end


                % Reward parameters (delay to go to the food port upon triger, duration
                % of reward, motor syringe speed)


                % ProductionReward: every vocalization of the bat is rewarded. If the
                % detected call reach thersholds and, if requested, hit any requirements in terms of
                % acoustic properties, then the LED cue turns on and the bat has xs
                % (MaxDelay) to come at the feedport and break the food beam to get a
                % reward
                
            elseif strcmp(value, 'Stop')
                fprintf('we are using the stop switch\n')
                stop_task(app)
            end
            
        end

        % Button pushed function: UpdateDataListButton
        function update_data_list_pushed(app, event)
            global Box
            %% List the files in the playback folder
            Files = dir(fullfile(Box.RecPath, '*_param.txt'));
            app.SelectDataDropDown.ItemsData = cell(length(Files),1);
            for ff=1:length(Files)
                app.SelectDataDropDown.Items{ff} = Files(ff).name;
                app.SelectDataDropDown.ItemsData{ff} = fullfile(Box.RecPath, Files(ff).name);
            end
        end

        % Button pushed function: UpdategraphButton
        function update_graph_pushed(app, event)
            % initialize the structure that will contain graph data
            app.GraphData = struct();
            % Get the recording data
            [DataPath, DataFile ,~]=fileparts(app.SelectDataDropDown.Value);
            WavFileStruc = dir(fullfile(DataPath, [DataFile(1:16) '*mic*.wav']));
            
            % Get the sound snippets from the sounds that triggered detection
            DataSnipStruc = dir(fullfile(DataPath, [DataFile(1:16) '*snippets/*.wav']));
            
            % Get the sample stamp of the detected vocalizations
            DataFileStruc = dir(fullfile(DataPath, [DataFile(1:16) '*events.txt']));
            Fid_Data = fopen(fullfile(DataFileStruc.folder,DataFileStruc.name));
            app.GraphData.EventsHeader = textscan(Fid_Data, '%s\t%s\t%s\t%s\t%s\t%s\t%s\n',1);
            for hh=1:length(app.GraphData.EventsHeader)
                if strfind(app.GraphData.EventsHeader{hh}{1}, 'SampleStamp')
                    app.GraphData.EventsStampCol = hh;
                elseif strfind(app.GraphData.EventsHeader{hh}{1}, 'Type')
                    app.GraphData.EventsEventTypeCol = hh;
                elseif strfind(app.GraphData.EventsHeader{hh}{1}, 'FoodPortFront')
                    app.GraphData.EventsFoodPortFrontCol = hh;
                elseif strfind(app.GraphData.EventsHeader{hh}{1}, 'FoodPortBack')
                    app.GraphData.EventsFoodPortBackCol = hh;
                end
            end
            app.GraphData.Events = textscan(Fid_Data, '%s\t%f\t%s\t%s\t%f\t%f\t%f');
            fclose(Fid_Data);
            VocId = find(strcmp('Vocalization', app.GraphData.Events{app.GraphData.EventsEventTypeCol}));
            
            
            % Calculate the offset of each soundfile output from the begining of the task
            app.GraphData.Offset_Y = zeros(length(WavFileStruc),1);
            for yy=2:length(WavFileStruc)
                Wavefile_local = fullfile(WavFileStruc(yy).folder, WavFileStruc(yy).name);
                [Y,FS] = audioread(Wavefile_local);
                % find the first snip of that sequence
                for ss=1:length(DataSnipStruc)
                    IndStamp1 = strfind(DataSnipStruc(ss).name, '_');
                    IndStamp_last = IndStamp1(end);
                    Sequence = str2double(DataSnipStruc(ss).name(IndStamp1(end-1)+1));
                    if Sequence == yy
                        [Ysnip,~] = audioread(fullfile(DataSnipStruc(ss).folder, DataSnipStruc(ss).name));
                        Stamp = str2double(DataSnipStruc(ss).name((IndStamp_last+1):end-3));
                        break
                    end
                end
                
                % Align that snip with the wav of the sequence and get the stamp of the snip
                C= normxcorr2(Ysnip, Y);
                Alligned = find(C==max(C));% This is the last sample that alligned to Ysnip in Y
                app.GraphData.Offset_Y(yy) = Stamp + length(Ysnip) -1 - Alligned; % Stamp is the position of the first sample of Ysnip
            end
            
            % Update the dropdown menu with all the detected calls
            FullStamps = app.GraphData.Events{app.GraphData.EventsStampCol}(VocId);
            if ~exist('FS', 'var')
                [~,FS] = audioread(fullfile(WavFileStruc(1).folder, WavFileStruc(1).name));
            end
            for ss=1:length(FullStamps)
                Ind_ = strfind(FullStamps{ss}, '_');
                Stamp = str2double(FullStamps{ss}((Ind_+1):end));
                MinStamp = floor(Stamp/(60*FS));
                SecStamp = (Stamp - (MinStamp*60*FS))/FS;
                app.SoundtimeDropDown.Items{ss} = sprintf('%d %dmin %.1fs', ss, MinStamp, SecStamp);
                app.SoundtimeDropDown.ItemsData{ss} = FullStamps{ss};
            end
            
            % call the callback function of the dropdown menu to plot the 2min around the first call detected
            update_graph_section(app,app.SoundtimeDropDown.Value)
            
        end

        % Button pushed function: TestFoodBackButton, 
        % TestFoodFrontButton, TestTriggerBackButton, 
        % TestTriggerFrontButton
        function detect_trigger_BB(app, event)
            global Box
            % Identify which Beam break we are testing
            if strcmp(event.Source.Text, 'Test T Front')
                BeamID = Box.BeamInputFront;
            elseif strcmp(event.Source.Text, 'Test T Back')
                BeamID = Box.BeamInputBack;
            elseif strcmp(event.Source.Text, 'Test F Front')
                BeamID = Box.BeamFoodFront;
            elseif strcmp(event.Source.Text, 'Test F Back')
                BeamID = Box.BeamFoodBack;
            else
                error('Unidentified beambreak name in detect_trigger_BB: %s\n',event.Source.Text)
            end
            % light up the cue LED (value set to 1) each time the beam is broken (value sent to 0) for RecDur seconds
            RecDur=10;
            app.BBLamp.Color = [1 0 0]; % set the LED for the triggers to red
            Timer=tic;
            while toc(Timer)<RecDur
                writeDigitalPin(Box.Arduino, Box.LEDCue, ~readDigitalPin(Box.Arduino, BeamID));
            end
            clear Timer
            writeDigitalPin(Box.Arduino, Box.LEDCue, 0); % Make sure LED Cue set back to 0
            app.BBLamp.Color = [0 0 0]; % set the LED for the triggers to black
        end

        % Value changed function: XmaxEditField, XminEditField
        function update_graph_xlim(app, event)
            global Box
            app.UIAxes.XLim(1)=app.XminEditField.Value*Box.SoundCard.fs;
            app.UIAxes.XLim(2)=app.XmaxEditField.Value*Box.SoundCard.fs;
            LengthY = app.UIAxes.XLim(2) - app.UIAxes.XLim(1);
            if (LengthY/Box.SoundCard.fs)>app.UIAxes.UserData.Res
                app.UIAxes.UserData.Step = round(LengthY/(Box.SoundCard.fs*app.UIAxes.UserData.Res));
            else
                app.UIAxes.UserData.Res=10;
                app.UIAxes.UserData.Step = round(LengthY/(Box.SoundCard.fs*app.UIAxes.UserData.Res),2);
            end
            app.UIAxes.XTick = app.UIAxes.XLim(1):(app.UIAxes.UserData.Step*Box.SoundCard.fs):app.UIAxes.XLim(2);
            app.UIAxes.XTickLabel = round(app.UIAxes.XLim(1)/Box.SoundCard.fs):app.UIAxes.UserData.Step:round(app.UIAxes.XLim(2)/Box.SoundCard.fs);
        end

        % Button pushed function: QUITButton
        function quit_voc_operant(app, event)
            clear soundmexpro
            clearvars -global Box
        end

        % Value changed function: SoundtimeDropDown
        function update_graph_section(app, event)
            global Box
            Ind_ = strfind(app.SoundtimeDropDown.Value, '_');
            Seq = str2double(app.SoundtimeDropDown.Value(1:(Ind_-1)));
            Stamp = str2double(app.SoundtimeDropDown.Value((Ind_+1):end));
            
            % Get the recording data for the corresponding sequence
            [DataPath, DataFile ,~]=fileparts(app.SelectDataDropDown.Value);
            WavFileStruc = dir(fullfile(DataPath, sprintf('%s*mic*_%d.wav',DataFile(1:16), Seq)));
            try
                Wavefile_local = fullfile(WavFileStruc.folder, WavFileStruc.name);
                [Y,FS] = audioread(Wavefile_local);
            catch
                fprintf(1,'Warning: the audiofile %s cannot be read properly and will not be plotted\n', Wavefile_local);
                Y = 0;
                FS=Box.SoundCard.fs;
            end
            Y_section_beg = max(1,Stamp - app.GraphData.Offset_Y(Seq) - 60*FS); % Make sure we don't request before the beginning of the section
            Pre_stamp = min(60*FS, Stamp - app.GraphData.Offset_Y(Seq));
            Y_section_end = min(length(Y), Stamp - app.GraphData.Offset_Y(Seq) + 60*FS); % Make sure we don't request after the beginning of the section
            Post_stamp = min(60*FS, length(Y)- (Stamp - app.GraphData.Offset_Y(Seq)));
            Y_section = Y(Y_section_beg:Y_section_end);
            
            % Plot the waveforms of the recording around the stamp of the vocalization
            cla(app.UIAxes)
            if ~ishold(app.UIAxes)
                hold(app.UIAxes)
            end
            plot(app.UIAxes, Y_section, 'Color', 'k')
            
            
            % plot the sound snippets in red on top
            % Get the sound snippets from the sounds that triggered detection
            DataSnipStruc = dir(fullfile(DataPath, sprintf('%s*snippets/*snipfile_%d*.wav', DataFile(1:16), Seq)));
            VocId = find(strcmp('Vocalization', app.GraphData.Events{app.GraphData.EventsEventTypeCol}));
            
            SnipDur = nan(length(VocId),1);
            Stamp_all = nan(length(VocId),1);
            VocId_all = nan(length(VocId),1);
            Voc_event_nb = 0;
            for ss=1:length(DataSnipStruc)
                IndStamp1 = strfind(DataSnipStruc(ss).name, '_');
                IndStamp_last = IndStamp1(end);
                if str2double(DataSnipStruc(ss).name((IndStamp_last+1) : (end-4))) < (Stamp + Post_stamp)
                    [Ysnip,FS] = audioread(fullfile(DataSnipStruc(ss).folder, DataSnipStruc(ss).name));
                    Stamp_local = str2double(DataSnipStruc(ss).name((IndStamp_last+1):end-3));
                    Stamp_local_context = Stamp_local - Stamp + Pre_stamp;
                    Sequence = str2double(DataSnipStruc(ss).name(IndStamp1(end-1)+1));
                    Voc_event_nb = Voc_event_nb + 1;
                    plot(app.UIAxes, Stamp_local_context:(Stamp_local_context+length(Ysnip)-1), Ysnip, 'Color', 'r')
                    % Search for the corresponding datapoint in the event log
                    Index =[];
                    for ii=1:length(VocId)
                        if strcmp(app.GraphData.Events{app.GraphData.EventsStampCol}{VocId(ii)}, sprintf('%d_%d', Sequence, Stamp_local))
                            Index = ii;
                            break
                        end
                    end
                    if isempty(Index)
                        error('event %d_%d cannot be found in the eventlog file\n', Sequence, Stamp_local);
                    else
                        Stamp_all(Voc_event_nb) = Stamp_local;
                        SnipDur(Voc_event_nb) = length(Ysnip);
                        VocId_all(Voc_event_nb) = VocId(Index);
                    end
                end
            end
            
            % plot the time stamp when sound was detected (last time point of each sound snippet)
            % Get the VocId should be plotted, from the log
            VocId_log = nan(1,length(VocId));
            Stamp_all_log = nan(1,length(VocId));
            SnipDur_log = nan(1,length(VocId));
            Voc_event_nb_log = 0;
            for ii=1:length(VocId)
                FullStamp = app.GraphData.Events{app.GraphData.EventsStampCol}{VocId(ii)};
                IndStamp = strfind(FullStamp, '_');
                Seq_local = str2double(FullStamp(1:(IndStamp-1)));
                Stamp_local = str2double(FullStamp((IndStamp+1):end));
                if Seq_local == Seq && (Stamp_local >= (Stamp-Pre_stamp)) && (Stamp_local <= (Stamp+Post_stamp)) % This is a vocalization from the same section
                    Voc_event_nb_log = Voc_event_nb_log+1;
                    VocId_log(Voc_event_nb_log) = VocId(ii);
                    Stamp_all_log(Voc_event_nb_log) = Stamp_local;
                    try
                        SnipDur_log(Voc_event_nb_log) = SnipDur(find(VocId_all==VocId(ii)));
                    catch
                        warning('This sound detection event does not have a corresponding sound snippet %s',FullStamp);
                        SnipDur_log(Voc_event_nb_log) = NaN;
                    end
                end
            end
            VocId_log = VocId_log(1:Voc_event_nb_log);
            Stamp_all_log = Stamp_all_log(1:Voc_event_nb_log);
            SnipDur_log = SnipDur_log(1:Voc_event_nb_log);
            
            ReFront = app.GraphData.Events{app.GraphData.EventsFoodPortFrontCol}(VocId_log);
            ReBack = app.GraphData.Events{app.GraphData.EventsFoodPortBackCol}(VocId_log);
            Colorcode = get(groot,'DefaultAxesColorOrder');
            ColorFront= nan(size(ReFront,1),3);
            ColorBack= ColorFront;
            ColorFront(find(ReFront==1),:) = repmat(Colorcode(3,:),sum(ReFront==1),1);
            ColorBack(find(ReBack==1),:) = repmat(Colorcode(3,:), sum(ReBack==1),1);
            ColorFront(find(ReFront==0),:) = repmat([0 0 0],sum(ReFront==0),1);
            ColorBack(find(ReBack==0),:) = repmat([0 0 0], sum(ReBack==0),1);
            ColorFront(find(isnan(ReFront)),:) = repmat([1 0 0],sum(isnan(ReFront)),1);
            ColorBack(find(isnan(ReBack)),:) = repmat([1 0 0], sum(isnan(ReBack)),1);
            sc=scatter(app.UIAxes, Stamp_all_log+SnipDur_log-1, 1.2*ones(length(VocId_log),1), 10, ColorFront, 'filled');
            sc=scatter(app.UIAxes, Stamp_all_log+SnipDur_log-1, 1.6*ones(length(VocId_log),1), 10, ColorBack, 'filled');
            hold(app.UIAxes)
            
            % let's fix the number of xticks to 20
            app.UIAxes.UserData=struct();
            app.UIAxes.UserData.Res=20;
            app.UIAxes.XLim(1) = 0;
            LengthY = length(Y_section);
            app.UIAxes.XLim(2) = LengthY;
            app.XminEditField.Value = app.UIAxes.XLim(1)/FS;
            app.XmaxEditField.Value = app.UIAxes.XLim(2)/FS;
            if (LengthY/FS)>app.UIAxes.UserData.Res
                app.UIAxes.UserData.Step = round(LengthY/(FS*app.UIAxes.UserData.Res));
            else
                app.UIAxes.UserData.Step = round(LengthY/(FS*app.UIAxes.UserData.Res),2);
            end
            app.UIAxes.XTick = 0:(app.UIAxes.UserData.Step*FS):LengthY;
            app.UIAxes.XTickLabel = 0:app.UIAxes.UserData.Step:round(LengthY/FS);
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = 'Sound pressure';
            app.UIAxes.YLim = [-1 2];
        end

        % Button pushed function: CalibratePlayBackGainButton
        function CalibratePlayBackGainButtonPushed(app, event)
            global Box
            Compensation = app.CompensationCheckBox.Value;
            if Compensation
                %load the impulse response calculated for the speaker
                SPFile = dir(fullfile(Box.RecPath,'AudioCompensation/Output/Comp_IR_scanspeak*.mat'));
                ii=1;
                if length(SPFile)>1
                    for ii=1:length(SPFile)
                        fprintf('%d %s\n', ii, SPFile(ii).name)
                    end
                    input('Indicate which impulse response file should be used for calibration:\n')
                elseif isempty(SPFile)
                    error('First create a impulse response file by using the calibration station on the intact recording chain\nThe filename should start by Comp_IR_scanspeak and be placed in:\n%s\n',fullfile(Box.RecPath,'AudioCompensation/Output/'));
                end
                SP = load(fullfile(SPFile(ii).folder,SPFile(ii).name));
    
                % load the impulse response of the microphone
                MicFile = dir(fullfile(Box.RecPath,'AudioCompensation/Output/Comp_IR_earthworks*.mat'));
                ii=1;
                if length(MicFile)>1
                    for ii=1:length(MicFile)
                        fprintf('%d %s\n', ii, MicFile(ii).name)
                    end
                    input('Indicate which impulse response file should be used for calibration:\n')
                elseif isempty(MicFile)
                    error('First create a impulse response file by using the calibration station on the intact recording chain\nThe filename should start by Comp_IR_scanspeak and be placed in:\n%s\n',fullfile(Box.RecPath,'AudioCompensation/Output/'));
                end
                Mic = load(fullfile(MicFile(ii).folder,MicFile(ii).name));
            else
                Mic=[];
                SP = [];
            end

            % load the recording used for calibration (a wav file that you create by extracting a recording made in the box)
            CalibFile = dir(fullfile(Box.RecPath,'AudioCompensation/RecCalib*'));
            ii=1;
            if length(CalibFile)>1
                for ii=1:length(CalibFile)
                    fprintf('%d %s\n', ii, CalibFile(ii).name)
                end
                input('Indicate which file should be used for calibration:\n')
            elseif isempty(CalibFile)
                error('Firts create a calibration file by recording any sound in the box using the intact recording chain\nThe filename should start by RecCalib and be placed in:\n%s\n',fullfile(Box.RecPath,'AudioCompensation/'));
            end
            [In,Fs] = audioread(fullfile(CalibFile(ii).folder, CalibFile(ii).name));
            
            % Start searching for the right gain. Set the initial gain to 1
            Gain_in = 1;
            findgainsoundmex(app, Box, In,Fs, Mic, SP, Gain_in, Compensation);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MetadataPanel = uipanel(app.UIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.BatinthebackEditFieldLabel = uilabel(app.MetadataPanel);
            app.BatinthebackEditFieldLabel.Text = 'Bat in the back';
            app.Batintheback = uieditfield(app.MetadataPanel, 'text');
            app.Batintheback.Value = 'BatBack';
            app.BatinthefrontEditFieldLabel = uilabel(app.MetadataPanel);
            app.BatinthefrontEditFieldLabel.Text = 'Bat in the front';
            app.Batinthefront = uieditfield(app.MetadataPanel, 'text');
            app.Batinthefront.Value = 'BatFront';
            app.SessionEditFieldLabel = uilabel(app.MetadataPanel);
            app.SessionEditFieldLabel.Text = 'Session #';
            app.SessionNum = uieditfield(app.MetadataPanel, 'numeric');
            app.SessionNum.UpperLimitInclusive = 'off';
            app.SessionNum.Limits = [1 Inf];
            app.SessionNum.ValueDisplayFormat = '%.0f';
            app.SessionNum.Value = 1;
            app.SessionTypeEditFieldLabel = uilabel(app.MetadataPanel);
            app.SessionTypeEditFieldLabel.Text = 'Session Type';
            app.SessionType = uieditfield(app.MetadataPanel, 'text');
            app.CommentBox = uitextarea(app.MetadataPanel);
            app.SubmitcommentsButton = uibutton(app.MetadataPanel, 'push');
            app.SubmitcommentsButton.Text = {'Submit'; 'comments:'};
            app.TaskPanel = uipanel(app.UIFigure);
            app.TaskPanel.Title = 'Task';
            app.TaskSwitch = uiswitch(app.TaskPanel, 'rocker');
            app.TaskSwitch.Items = {'Stop', 'Start'};
            app.TaskSwitch.ValueChangedFcn = createCallbackFcn(app, @task_switch_action, true);
            app.TaskSwitch.Value = 'Stop';
            app.TaskDropDown = uidropdown(app.TaskPanel);
            app.TaskDropDown.Items = {'Record Only', 'All Vocalizations Rewarded', 'Food port Rewarded', 'Input Beam Rewarded', 'Input Beam + vocal production rewarded', 'Input Beam or vocal production reward', 'Play-Back only'};
            app.TaskDropDown.ItemsData = {'RecOnly', 'VocTrigger', 'FPTrigger', 'BBTrigger', 'BBVocTrigger', 'BBorVocTrigger', 'PBOnly'};
            app.TaskDropDown.Value = 'RecOnly';
            app.Lamp = uilamp(app.TaskPanel);
            app.Status_label = uilabel(app.TaskPanel);
            app.Status_label.Text = 'Status';
            app.NooutputCheckBox = uicheckbox(app.TaskPanel);
            app.NooutputCheckBox.Text = 'No output';
            app.TransceiverSyncCheckBox = uicheckbox(app.TaskPanel);
            app.TransceiverSyncCheckBox.Text = 'Transceiver Sync';
            app.QUITButton = uibutton(app.UIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @quit_voc_operant, true);
            app.QUITButton.Text = 'QUIT';
            app.ParametersTab = uitabgroup(app.UIFigure);
            app.SessionTab = uitab(app.ParametersTab);
            app.SessionTab.Title = 'Session';
            app.DurationhoursEditFieldLabel = uilabel(app.SessionTab);
            app.DurationhoursEditFieldLabel.Text = 'Duration (hours)';
            app.DurationhoursEditField = uieditfield(app.SessionTab, 'numeric');
            app.DurationhoursEditField.Limits = [0 Inf];
            app.DurationhoursEditField.Value = 4;
            app.AutomaticoffafterCheckBox = uicheckbox(app.SessionTab);
            app.AutomaticoffafterCheckBox.Text = 'Automatic off after:';
            app.EnforcesleephoursCheckBox = uicheckbox(app.SessionTab);
            app.EnforcesleephoursCheckBox.Text = 'Enforce sleep hours';
            app.FromEditFieldLabel = uilabel(app.SessionTab);
            app.FromEditFieldLabel.Text = 'From';
            app.SleepTimeH = uieditfield(app.SessionTab, 'numeric');
            app.SleepTimeH.Limits = [0 23];
            app.SleepTimeH.ValueDisplayFormat = '%.0f';
            app.Label = uilabel(app.SessionTab);
            app.Label.Text = ':';
            app.SleepTMin = uieditfield(app.SessionTab, 'numeric');
            app.SleepTMin.Limits = [0 59];
            app.SleepTMin.ValueDisplayFormat = '%.0f';
            app.ForEditFieldLabel = uilabel(app.SessionTab);
            app.ForEditFieldLabel.Text = 'For';
            app.SleepDur = uieditfield(app.SessionTab, 'numeric');
            app.SleepDur.Limits = [0 16];
            app.SleepDur.ValueDisplayFormat = '%.0f';
            app.hrsLabel = uilabel(app.SessionTab);
            app.hrsLabel.Text = 'hrs';
            app.SounddetectionTab = uitab(app.ParametersTab);
            app.SounddetectionTab.Title = 'Sound detection';
            app.SoundCardLabel = uilabel(app.SounddetectionTab);
            app.SoundCardLabel.Text = 'Sound Card';
            app.ChannelListBoxLabel = uilabel(app.SounddetectionTab);
            app.ChannelListBoxLabel.Text = 'Channel';
            app.SoundDetecChannel = uilistbox(app.SounddetectionTab);
            app.SoundDetecChannel.Items = {'1', '2', '3', '4'};
            app.SoundDetecChannel.Multiselect = 'on';
            app.SoundDetecChannel.Enable = 'off';
            app.SoundDetecChannel.Value = {'1'};
            app.TriggerthresholdsLabel = uilabel(app.SounddetectionTab);
            app.TriggerthresholdsLabel.Text = 'Trigger thresholds';
            app.SoundbufferdurationsecSpinnerLabel = uilabel(app.SounddetectionTab);
            app.SoundbufferdurationsecSpinnerLabel.Text = 'Sound buffer duration (sec)';
            app.SoundbufferdurationsecSpinner = uispinner(app.SounddetectionTab);
            app.SoundbufferdurationsecSpinner.Limits = [0 60];
            app.SoundbufferdurationsecSpinner.Enable = 'off';
            app.SoundbufferdurationsecSpinner.Value = 1;
            app.DurationmsEditFieldLabel = uilabel(app.SounddetectionTab);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.DurationmsEditField.Limits = [0 Inf];
            app.DurationmsEditField.Value = 7;
            app.AmplitudedBEditFieldLabel = uilabel(app.SounddetectionTab);
            app.AmplitudedBEditFieldLabel.Text = 'Amplitude (dB)';
            app.AmplitudedBEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.AmplitudedBEditField.Limits = [-50 0];
            app.AmplitudedBEditField.Value = -35;
            app.HighpassfilterkHzLabel = uilabel(app.SounddetectionTab);
            app.HighpassfilterkHzLabel.Text = 'High pass filter                     kHz';
            app.HighpassfilterkHzEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.HighpassfilterkHzEditField.Limits = [0 Inf];
            app.HighpassfilterkHzEditField.Value = 20;
            app.RMSEditFieldLabel = uilabel(app.SounddetectionTab);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMSEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.RMSEditField.Limits = [0 Inf];
            app.RMSEditField.Value = 0.005;
            app.FiledurationminSpinnerLabel = uilabel(app.SounddetectionTab);
            app.FiledurationminSpinnerLabel.Text = 'File duration (min)';
            app.FiledurationminSpinner = uispinner(app.SounddetectionTab);
            app.FiledurationminSpinner.Limits = [1 30];
            app.FiledurationminSpinner.ValueDisplayFormat = '%.0f';
            app.FiledurationminSpinner.Editable = 'off';
            app.FiledurationminSpinner.Enable = 'off';
            app.FiledurationminSpinner.Value = 10;
            app.LowpassfilterkHzEditFieldLabel = uilabel(app.SounddetectionTab);
            app.LowpassfilterkHzEditFieldLabel.Text = 'Low pass filter                     kHz';
            app.LowpassfilterkHzEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.LowpassfilterkHzEditField.Limits = [0 Inf];
            app.LowpassfilterkHzEditField.Value = 90;
            app.LowPassCheckBox = uicheckbox(app.SounddetectionTab);
            app.LowPassCheckBox.Text = '';
            app.HighPassCheckBox = uicheckbox(app.SounddetectionTab);
            app.HighPassCheckBox.Text = '';
            app.HighPassCheckBox.Value = true;
            app.MotuGaindBEditFieldLabel = uilabel(app.SounddetectionTab);
            app.MotuGaindBEditFieldLabel.Text = 'Motu Gain (dB)';
            app.MotuGaindBEditField = uieditfield(app.SounddetectionTab, 'numeric');
            app.MotuGaindBEditField.Value = 10;
            app.PlaybackTab = uitab(app.ParametersTab);
            app.PlaybackTab.Title = 'Playback';
            app.SoundCardLabel_2 = uilabel(app.PlaybackTab);
            app.SoundCardLabel_2.Text = 'Sound Card';
            app.ChannelListBoxLabel_2 = uilabel(app.PlaybackTab);
            app.ChannelListBoxLabel_2.Text = 'Channel';
            app.PlaybackChannel = uilistbox(app.PlaybackTab);
            app.PlaybackChannel.Items = {'Main Out 1', 'Main Out 2', 'Analog 1', 'Analog 2'};
            app.PlaybackChannel.Multiselect = 'on';
            app.PlaybackChannel.Enable = 'off';
            app.PlaybackChannel.Value = {'Main Out 1'};
            app.SelectListBoxLabel = uilabel(app.PlaybackTab);
            app.SelectListBoxLabel.Text = 'Select';
            app.PlayListBox = uilistbox(app.PlaybackTab);
            app.PlayListBox.Multiselect = 'on';
            app.PlayListBox.Value = {'Item 1'};
            app.SoundfilesLabel = uilabel(app.PlaybackTab);
            app.SoundfilesLabel.Text = 'Sound files';
            app.UpdatefilelistButton = uibutton(app.PlaybackTab, 'push');
            app.UpdatefilelistButton.ButtonPushedFcn = createCallbackFcn(app, @update_file_list_pushed, true);
            app.UpdatefilelistButton.Text = 'Update file list';
            app.RepetitionEditFieldLabel = uilabel(app.PlaybackTab);
            app.RepetitionEditFieldLabel.Text = 'Repetition';
            app.RepetitionEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.RepetitionEditField.Limits = [1 Inf];
            app.RepetitionEditField.Value = 15;
            app.MinIntercallintervalsEditFieldLabel = uilabel(app.PlaybackTab);
            app.MinIntercallintervalsEditFieldLabel.Text = 'Min Inter-call interval (s)';
            app.MinIntercallintervalsEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.MinIntercallintervalsEditField.Limits = [1 Inf];
            app.MinIntercallintervalsEditField.Value = 1;
            app.MaxIntercallintervalsEditFieldLabel = uilabel(app.PlaybackTab);
            app.MaxIntercallintervalsEditFieldLabel.Text = 'Max Inter-call interval (s)';
            app.MaxIntercallintervalsEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.MaxIntercallintervalsEditField.Limits = [1 Inf];
            app.MaxIntercallintervalsEditField.Value = 5;
            app.CalibratePlayBackGainButton = uibutton(app.PlaybackTab, 'push');
            app.CalibratePlayBackGainButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratePlayBackGainButtonPushed, true);
            app.CalibratePlayBackGainButton.Text = 'Calibrate PlayBack Gain';
            app.CurrentRepEditFieldLabel = uilabel(app.PlaybackTab);
            app.CurrentRepEditFieldLabel.Text = 'Current Rep #';
            app.CurrentRepEditField = uieditfield(app.PlaybackTab, 'numeric');
            app.CurrentRepEditField.Editable = 'off';
            app.CurrentRepEditField.Enable = 'off';
            app.PlayingEditFieldLabel = uilabel(app.PlaybackTab);
            app.PlayingEditFieldLabel.Text = 'Playing:';
            app.PlayingEditField = uieditfield(app.PlaybackTab, 'text');
            app.PlayingEditField.Editable = 'off';
            app.PlayingEditField.Enable = 'off';
            app.CompensationCheckBox = uicheckbox(app.PlaybackTab);
            app.CompensationCheckBox.Text = 'Compensation';
            app.PlaybackTaskparametersLabel = uilabel(app.PlaybackTab);
            app.PlaybackTaskparametersLabel.Text = 'Playback Task parameters';
            app.RewardTab = uitab(app.ParametersTab);
            app.RewardTab.Title = 'Reward';
            app.MotorspeedSpinnerLabel = uilabel(app.RewardTab);
            app.MotorspeedSpinnerLabel.Text = 'Motor speed';
            app.MotorspeedSpinner = uispinner(app.RewardTab);
            app.MotorspeedSpinner.Step = 0.1;
            app.MotorspeedSpinner.Limits = [-1 1];
            app.MotorspeedSpinner.Value = 1;
            app.MaxrewardsEditFieldLabel = uilabel(app.RewardTab);
            app.MaxrewardsEditFieldLabel.Text = 'Max # rewards';
            app.MaxrewardsEditField = uieditfield(app.RewardTab, 'numeric');
            app.MaxrewardsEditField.Limits = [0 180];
            app.MaxrewardsEditField.ValueDisplayFormat = '%.0f';
            app.MaxrewardsEditField.Value = 180;
            app.RewarddurationsEditFieldLabel = uilabel(app.RewardTab);
            app.RewarddurationsEditFieldLabel.Text = 'Reward duration (s)';
            app.RewarddurationsEditField = uieditfield(app.RewardTab, 'numeric');
            app.RewarddurationsEditField.Limits = [0 2];
            app.RewarddurationsEditField.Value = 0.2;
            app.TimedelaytoretrieverewardsEditFieldLabel = uilabel(app.RewardTab);
            app.TimedelaytoretrieverewardsEditFieldLabel.Text = 'Time delay to retrieve reward (s)';
            app.TimedelaytoretrieverewardsEditField = uieditfield(app.RewardTab, 'numeric');
            app.TimedelaytoretrieverewardsEditField.Limits = [0 30];
            app.TimedelaytoretrieverewardsEditField.Value = 5;
            app.CurrentrewardsdeliveredEditFieldLabel = uilabel(app.RewardTab);
            app.CurrentrewardsdeliveredEditFieldLabel.Text = 'Current # rewards delivered';
            app.CurrentrewardsdeliveredEditField = uieditfield(app.RewardTab, 'numeric');
            app.CurrentrewardsdeliveredEditField.Enable = 'off';
            app.LEDCueonDropDownLabel = uilabel(app.RewardTab);
            app.LEDCueonDropDownLabel.Text = 'LED Cue on';
            app.LEDCueonDropDown = uidropdown(app.RewardTab);
            app.LEDCueonDropDown.Items = {'Until reward or delay reached', 'For a strict 1s', 'For a strict 2s', 'For a strict 5s'};
            app.LEDCueonDropDown.Value = 'Until reward or delay reached';
            app.Delay2retrieverewardStrictFlexDropDown = uidropdown(app.RewardTab);
            app.Delay2retrieverewardStrictFlexDropDown.Items = {'Flexible', 'Strict'};
            app.Delay2retrieverewardStrictFlexDropDown.Value = 'Strict';
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditFieldLabel = uilabel(app.RewardTab);
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditFieldLabel.Text = {'Time Delay to Vocalize (s)'; '(for task Input Beam + Voc)'};
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField = uieditfield(app.RewardTab, 'numeric');
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Limits = [0 Inf];
            app.TimeDelaytoVocalizesfortaskInputBeamVocEditField.Value = 5;
            app.RewardPlayBackCheckBox = uicheckbox(app.RewardTab);
            app.RewardPlayBackCheckBox.Text = 'Reward PlayBack';
            app.RewardPlayBackCheckBox.Value = true;
            app.PunishmentTab = uitab(app.ParametersTab);
            app.PunishmentTab.Title = 'Punishment';
            app.DurationsecEditFieldLabel = uilabel(app.PunishmentTab);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.TimeOutDuration = uieditfield(app.PunishmentTab, 'numeric');
            app.TimeOutDuration.Limits = [0 Inf];
            app.TimeOutDuration.Value = 120;
            app.TimeoutforignoringthecueCheckBox = uicheckbox(app.PunishmentTab);
            app.TimeoutforignoringthecueCheckBox.Text = 'Time out for ignoring the cue';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditFieldLabel = uilabel(app.PunishmentTab);
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditFieldLabel.Text = {'# unsuccessful reward retrieval'; 'to trigger Dunce cap'};
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField = uieditfield(app.PunishmentTab, 'numeric');
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Limits = [0 Inf];
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.ValueDisplayFormat = '%.0f';
            app.unsuccessfulrewardretrievaltotriggerDuncecapEditField.Value = 10;
            app.DuncecapforignoringthecueCheckBox = uicheckbox(app.PunishmentTab);
            app.DuncecapforignoringthecueCheckBox.Text = 'Dunce cap for ignoring the cue';
            app.DurationsecEditFieldLabel_2 = uilabel(app.PunishmentTab);
            app.DurationsecEditFieldLabel_2.Text = 'Duration (sec)';
            app.DunceCapDuration = uieditfield(app.PunishmentTab, 'numeric');
            app.DunceCapDuration.Limits = [0 Inf];
            app.DunceCapDuration.Value = 600;
            app.currentunsuccessfulrewardretrievalEditFieldLabel = uilabel(app.PunishmentTab);
            app.currentunsuccessfulrewardretrievalEditFieldLabel.Text = {'current # unsuccessful reward retrieval'; ''};
            app.currentunsuccessfulrewardretrievalEditField = uieditfield(app.PunishmentTab, 'numeric');
            app.currentunsuccessfulrewardretrievalEditField.Limits = [0 Inf];
            app.currentunsuccessfulrewardretrievalEditField.ValueDisplayFormat = '%.0f';
            app.currentunsuccessfulrewardretrievalEditField.Enable = 'off';
            app.ResultsTab = uitab(app.ParametersTab);
            app.ResultsTab.Title = 'Results';
            app.SelectsessionLabel = uilabel(app.ResultsTab);
            app.SelectsessionLabel.Text = 'Select session';
            app.SelectDataDropDown = uidropdown(app.ResultsTab);
            app.UpdateDataListButton = uibutton(app.ResultsTab, 'push');
            app.UpdateDataListButton.ButtonPushedFcn = createCallbackFcn(app, @update_data_list_pushed, true);
            app.UpdateDataListButton.Text = 'Update Data List';
            app.UpdategraphButton = uibutton(app.ResultsTab, 'push');
            app.UpdategraphButton.ButtonPushedFcn = createCallbackFcn(app, @update_graph_pushed, true);
            app.UpdategraphButton.Text = 'Update graph';
            app.BackReLabel = uilabel(app.ResultsTab);
            app.BackReLabel.Text = 'Back Re';
            app.FrontReLabel = uilabel(app.ResultsTab);
            app.FrontReLabel.Text = 'Front Re';
            app.BackVocLabel = uilabel(app.ResultsTab);
            app.BackVocLabel.Text = 'Back Voc';
            app.FrontVocLabel = uilabel(app.ResultsTab);
            app.FrontVocLabel.Text = 'Front Voc';
            app.XminEditFieldLabel = uilabel(app.ResultsTab);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.ResultsTab, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @update_graph_xlim, true);
            app.XmaxEditFieldLabel = uilabel(app.ResultsTab);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.ResultsTab, 'numeric');
            app.XmaxEditField.ValueChangedFcn = createCallbackFcn(app, @update_graph_xlim, true);
            app.SoundtimeDropDownLabel = uilabel(app.ResultsTab);
            app.SoundtimeDropDownLabel.Text = 'Sound time';
            app.SoundtimeDropDown = uidropdown(app.ResultsTab);
            app.SoundtimeDropDown.ValueChangedFcn = createCallbackFcn(app, @update_graph_section, true);
            app.UIAxes = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Sound Presssure')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.BoxmanualcontrolPanel = uipanel(app.UIFigure);
            app.BoxmanualcontrolPanel.Title = 'Box manual control';
            app.ResetButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @reset_button_pushed, true);
            app.ResetButton.Text = 'Reset';
            app.FwdButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.FwdButton.ButtonPushedFcn = createCallbackFcn(app, @fwd_button_pushed, true);
            app.FwdButton.Text = '+';
            app.BwdButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.BwdButton.ButtonPushedFcn = createCallbackFcn(app, @bwd_button_pushed, true);
            app.BwdButton.Text = '-';
            app.MotorLabel = uilabel(app.BoxmanualcontrolPanel);
            app.MotorLabel.Text = 'Motor';
            app.LEDLabel = uilabel(app.BoxmanualcontrolPanel);
            app.LEDLabel.Text = 'LED';
            app.TestCueButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestCueButton.ButtonPushedFcn = createCallbackFcn(app, @cue_LED_flash, true);
            app.TestCueButton.Text = 'Test Cue';
            app.TestTimeoutButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestTimeoutButton.ButtonPushedFcn = createCallbackFcn(app, @time_out_LED_flash, true);
            app.TestTimeoutButton.Text = 'Test Time out';
            app.SpeakerLabel = uilabel(app.BoxmanualcontrolPanel);
            app.SpeakerLabel.Text = 'Speaker';
            app.MicLabel = uilabel(app.BoxmanualcontrolPanel);
            app.MicLabel.Text = 'Mic';
            app.PlayButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_button_pushed, true);
            app.PlayButton.Text = 'Play';
            app.Detect = uibutton(app.BoxmanualcontrolPanel, 'state');
            app.Detect.ValueChangedFcn = createCallbackFcn(app, @detect_sound_mic, true);
            app.Detect.Text = 'Detect';
            app.MicLamp = uilamp(app.BoxmanualcontrolPanel);
            app.BeamBreakersLabel = uilabel(app.BoxmanualcontrolPanel);
            app.BeamBreakersLabel.Text = 'Beam Breakers';
            app.TestTriggerFrontButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestTriggerFrontButton.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestTriggerFrontButton.Text = 'Test T Front';
            app.TestTriggerBackButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestTriggerBackButton.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestTriggerBackButton.Text = 'Test T Back';
            app.TestFoodFrontButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestFoodFrontButton.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestFoodFrontButton.Text = 'Test F Front';
            app.TestFoodBackButton = uibutton(app.BoxmanualcontrolPanel, 'push');
            app.TestFoodBackButton.ButtonPushedFcn = createCallbackFcn(app, @detect_trigger_BB, true);
            app.TestFoodBackButton.Text = 'Test F Back';
            app.TriggersLabel = uilabel(app.BoxmanualcontrolPanel);
            app.TriggersLabel.Text = 'Triggers';
            app.FoodportsLabel = uilabel(app.BoxmanualcontrolPanel);
            app.FoodportsLabel.Text = 'Food ports';
            app.BBLamp = uilamp(app.BoxmanualcontrolPanel);
            app.BoxIDLabel = uilabel(app.UIFigure);
            app.BoxIDLabel.Text = 'Box ID';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vocOperant(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end